classdef BEL1D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEL1DUIFigure               matlab.ui.Figure
        Title                       matlab.ui.control.Label
        UI_Logos                    matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        IntroductionTab             matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        CopyrightInformation        matlab.ui.control.TextArea
        AuthorInformation           matlab.ui.control.TextArea
        TextArea                    matlab.ui.control.TextArea
        DataType_ButtonGroup        matlab.ui.container.ButtonGroup
        SNMRButton                  matlab.ui.control.ToggleButton
        SurfaceWavesButton          matlab.ui.control.ToggleButton
        OthersButton                matlab.ui.control.ToggleButton
        PriorType_ButtonGroup       matlab.ui.container.ButtonGroup
        UniformButton               matlab.ui.control.ToggleButton
        GaussianButton              matlab.ui.control.ToggleButton
        LoadmodelsButton            matlab.ui.control.ToggleButton
        PreparationofBEL1DTab       matlab.ui.container.Tab
        NumberofpriorrealizationsEditFieldLabel  matlab.ui.control.Label
        NumberofpriorrealizationsEditField  matlab.ui.control.NumericEditField
        ModelsAndConditions         matlab.ui.container.TabGroup
        ModelSpaceTab               matlab.ui.container.Tab
        PriorModelTable             matlab.ui.control.Table
        PriorSaveButton             matlab.ui.control.Button
        PriorLoadButton             matlab.ui.control.Button
        PriorInitializeButton       matlab.ui.control.Button
        ConditionsTab               matlab.ui.container.Tab
        ConditionsTable             matlab.ui.control.Table
        NoConditionsLabel           matlab.ui.control.Label
        ConditionsSaveButton        matlab.ui.control.Button
        ConditionsLoadButton        matlab.ui.control.Button
        ConditionsInitializeButton  matlab.ui.control.Button
        ParametersselectionTab      matlab.ui.container.Tab
        ParametersSelectTable       matlab.ui.control.Table
        SelectallParamButton        matlab.ui.control.Button
        UnselectallParamButton      matlab.ui.control.Button
        LoadParamButton             matlab.ui.control.Button
        SaveParamButton             matlab.ui.control.Button
        AddingConditionsButton      matlab.ui.control.StateButton
        RunBEL1DPreparationButton   matlab.ui.control.Button
        LoadingparametersButton     matlab.ui.control.Button
        NoDescriptionText           matlab.ui.control.Label
        PostacquisitionBEL1DTab     matlab.ui.container.Tab
        KDEAxis                     matlab.ui.control.UIAxes
        CCAdimensiontodisplaySpinnerLabel  matlab.ui.control.Label
        CCAdimSpinner               matlab.ui.control.Spinner
        LoadFieldDataButton         matlab.ui.control.Button
        NumberofposteriormodelsEditFieldLabel  matlab.ui.control.Label
        NbPostEditField             matlab.ui.control.NumericEditField
        LoadPREBELButton            matlab.ui.control.Button
        RunBEL1DButton              matlab.ui.control.Button
        SavePOSTBELButton           matlab.ui.control.Button
        PostProcessButton           matlab.ui.control.Button
        ForwardPostButton           matlab.ui.control.Button
        OpenexternallyButton        matlab.ui.control.Button
        NoiseCheckBox               matlab.ui.control.CheckBox
        ValuenVEditFieldLabel       matlab.ui.control.Label
        ValuenVEditField            matlab.ui.control.NumericEditField
        IterateCheckBox             matlab.ui.control.CheckBox
        IterationsEditFieldLabel    matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        PREBEL  % All the elements for the preparation of BEL
        POSTBEL % All the elements for the post-acquisition processing
        STATE   % The state of the app
        ITER % Description
    end

    methods (Access = private)
    
        function [condition, listEqual] = ConditionsGather(app)
            % Function that gathers the conditions from the condition table and transforms them 
            % into a function to check for the conditions (condition).
            % CONDITIONSGATHER also retrurns the list of equals parameters (listEqual).
            
            % Getting the specific conditions:
            paramNames = app.PREBEL.Models.param.names;
            nbParam = length(paramNames);
            condition = '@(variable) [true ';
            listEqual = false(nbParam,nbParam);% Ignoring the diagonal (implicit)
            nbParam = length(paramNames);
            ConditionsTab_tmp = app.ConditionsTable.Data;
            for i = 1 : nbParam
                for j = i+1 : nbParam
                    if ~(isequal(ConditionsTab_tmp{i,j},'NaN')) && ~isequal(ConditionsTab_tmp{i,j},'=')
                        condition = [condition ' & variable(:,' num2str(i) ')' ConditionsTab_tmp{i,j} 'variable(:,' num2str(j) ')'];
                    elseif isequal(ConditionsTab_tmp{i,j},'=')
                        listEqual(i,j) = true;
                        listEqual(j,i) = true;
                    end
                end
            end
            condition = [condition '];'];    
            condition = str2func(condition);
        end
        
        function models = SamplingMod(app,progress)
            % Function that samples models constrained to the prior model space and
            % (eventually) the conditions stated in the "conditions" table. It returns a 
            % structure containing the different elements of the models individualy as well
            % as grouped.
            
        %%%%%%%%% Latin-Hypercube sampling? %%%%%%%%
            LHsampling = false; % Not usefull for large samplings
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Gathering the essential informations
            type = app.PREBEL.priorType; % Type of sampling (1 = uniform, 2 = gaussian)
            nbModels = app.NumberofpriorrealizationsEditField.Value;% Number of prior realizations
            paramNames = app.PREBEL.Models.param.names;
            nbLayers = app.PREBEL.Models.nbLayers;
            nbParam = length(paramNames);
            param = app.PREBEL.Models.param.values;% Ranges or mean and std
            if app.AddingConditionsButton.Value
                [condFun, listEqual] = ConditionsGather(app);% Keep only models that corresponds to true!!!
            else
                condFun = @(variable) true;
                listEqual = false(nbParam,nbParam);
            end   
            j = 1;
            k = 1;
            mins = zeros(1,nbParam);
            maxs = zeros(1,nbParam);
            for i = 1 : nbParam
                if k > nbLayers || (j==1 && isnan(param(k,j)))
                    j = j + 2;
                    k = 1;
                end
                mins(i) = param(k,j);
                maxs(i) = param(k,j+1);
                k = k + 1;
            end
            % Sampling of the variables:
            if LHsampling
                % Latin-Hypercube samplers - no conditions
                if type ~= 2 % Uniformely distributed variables
                    modelsTmp = ones(nbModels,nbParam)*diag(mins) + lhsdesign(nbModels,nbParam) * diag(maxs-mins);
                else % Gaussian variables
                    modelsTmp = lhsnorm(mins,diag(maxs),nbModels);
                end            
            else
                progress.Indeterminate = 'off';
                NOTOK = true;
                modelsTmp = zeros(nbModels,nbParam);
                indice = 1;
                remains = nbModels;
                progress.Value = 1-remains/nbModels;
                loopIter = 0;
                passed = false;
                % Classical sampler - possible conditions
                while NOTOK
                    if type ~= 2 % Uniformely distributed variables
                        temporary = ones(remains,nbParam)*diag(mins) + rand(remains,nbParam) * diag(maxs-mins);
                    else % Gaussian variables
                        temporary = repmat(mins,remains,1) + randn(remains,nbParam)*diag(maxs);
                    end
                    if type~=2
                        cond = condFun(temporary);
                    else
                        condParam = temporary>0; % Physical properties are not negative!
                        condParam = all(condParam,2);
                        cond = condFun(temporary) & condParam;
                    end
                    if loopIter < 1 && size(cond,1)==1
                        modelsTmp = temporary;
                        NOTOK = false;
                    else
                        modelsTmp(indice:indice+sum(cond)-1,:) = temporary(cond,:);
                        indice = indice+sum(cond);
                        remains = nbModels - indice + 1;
                        progress.Value = 1-remains/nbModels;
                        if indice > nbModels
                            NOTOK = false;
                        end
                        loopIter = loopIter + 1;
                        if loopIter > 100 && ~passed
                            uialert(app.BEL1DUIFigure,'The conditions may be difficult to achieve with the prior!','Warning','Icon','warning');
                            passed = true;
                        end
                        if loopIter > 10000
                            uialert(app.BEL1DUIFigure,'Impossible to achieve the set of conditions within the prior!','Error','Icon','error');
                            models = [];
                            return
                        end
                    end
                end
            end
            for i = 1 : nbParam
                for j = i + 1 : nbParam
                    if listEqual(i,j)
                        modelsTmp(:,j) = modelsTmp(:,i);
                    end
                end
            end
            % Structuring the data:
            models = [];
            for i = 1 : nbParam
                models.(paramNames{i}) = modelsTmp(:,i);
            end
            models.All = modelsTmp;
            models.CondFun = condFun;
            models.listEqual = listEqual;
        end
        
        function Models = ForwardFID(app,Models,progress)
            % Models = app.PREBEL.Models;
            % The app.STATE.forward structure contains:
            %   forward.t_dead -> The dead time of measurement
            %   forward.frequency -> The measurement frequency
            %   forward.t_max -> The maximum measurement time
            %   forward.t_register -> The times at each measurements
            %   forward.kernel
% Old version 02/04/2020
            kdata = app.PREBEL.forward.kernel;
            % t_dead = app.STATE.forward.t_dead;% In seconds
            % f_measure = app.STATE.forward.frequency;% In Hz
            % t_max = app.STATE.forward.t_max;% In seconds
            t_register = app.PREBEL.forward.t_register;% Timing of datapoints
            fid = zeros(Models.N,size(kdata.K,1),length(t_register));% Initialization of the matrix containing the data
            % Default is not to consider complex values (absolute value of the
            % signal used)
            fid = complex(fid);% To be able to store complex values
            nbLayers = size(Models.param.values,1);
            zModels = [zeros(Models.N,1) cumsum(Models.models.All(:,1:nbLayers-1),2) repmat(kdata.model.zmax,Models.N,1)];
            indexChange = zModels(:,end)<zModels(:,end-1);
            if nbLayers > 2
                zModels(indexChange,end-1) = mean([zModels(indexChange,end) zModels(indexChange,end-2)],2);
            else
                zModels(indexChange,end-1) = zModels(indexChange,end) - ones(sum(indexChange),1);
            end
            wModels = Models.models.All(:,nbLayers:2*nbLayers-1)./1e2;
            T2Models = Models.models.All(:,2*nbLayers:end)./1e3;
            for i = 1 : Models.N
                progress.Value = i/Models.N;
                % Initializing the models to correspond to the kernel file
                fi   = zeros(length(kdata.model.z),1);
                T2si = zeros(length(kdata.model.z),1);
                for j = 1 : nbLayers
                    indexLayer = zModels(i,j) <= kdata.model.z & kdata.model.z < zModels(i,j+1);% Capturing the indexes in K discretization that are larger than the current depth and lwoer than the next
                    indexLayer = find(indexLayer);% Changing to numeric indexes
                    fi(indexLayer) = wModels(i,j);
                    T2si(indexLayer) = T2Models(i,j);
                    
                end
                fi(end) = wModels(i,end);
                T2si(end) = T2Models(i,end);
                % Computing the FID
                ft = zeros(length(kdata.model.z), length(t_register));
                for m = 1:length(kdata.model.z)
                    ft(m,:) = fi(m) * exp(-t_register./T2si(m));
                end
                fid(i,:,:) = kdata.K * ft;
            end
% New version (02/04/20) - inspiered from pyGimli
%             K = app.PREBEL.forward.kernel.K;
%             zv = app.PREBEL.forward.kernel.model.z;
%             T = app.PREBEL.forward.t_register;
%             nl = size(Models.param.values,1);
%             fid = complex(zeros(Models.N,size(K,1),length(T)));
%             for i = 1 : Models.N
%                 progress.Value = i/Models.N;
%                 thk = Models.models.All(i,1:nl-1);
%                 wc = Models.models.All(i,nl:2*nl-1)./1e2;
%                 t2 = Models.models.All(i,2*nl:end)./1e3;
%                 zthk = cumsum(thk);
%                 lzv = length(zv);
%                 izvec = ones(nl+1,1);
%                 rzvec = zeros(nl+1,1);
%                 % rzvec(1) = 1;
%                 for j = 1 : nl-1
%                     outside = false;
%                     ii = find(zv < zthk(j),1,'last');
%                     if isempty(ii) % The first layer is already smaller than the first layer in zv!
%                         ii = 1;
%                     elseif ii == lzv
%                         outside = true;
%                     else
%                         ii = ii + 1;
%                     end
%                     izvec(j+1) = ii;
%                     if ii == 1 % The upper layer begins at 0;
%                         rzvec(j+1) = zthk(j)/zv(ii);
%                     elseif ii <= lzv
%                         if ~outside
%                             rzvec(j+1) = (zthk(j) - zv(ii-1))/(zv(ii)-zv(ii-1));
%                         else % There is no sensitivity outside the kernel!
%                             rzvec(j+1) = 0;
%                         end
%                     end
%                 end
%                 izvec(end) = lzv;
%                 A = complex(zeros(size(K,1),length(T)));
%                 for j = 1 : nl
%                     wcvec = zeros(lzv,1);
%                     if izvec(j+1) ~= 1
%                         wcvec(izvec(j)+1:izvec(j+1)-1) = wc(j);
%                     end
%                     wcvec(izvec(j)) = wc(j)*(1-rzvec(j));
%                     if izvec(j+1) < lzv
%                         wcvec(izvec(j+1)) = wc(j)*(rzvec(j+1));
%                     end
% %                     if length(wcvec) > 96
% %                         error = 1;
% %                     end
%                     amps = K*wcvec;
% %                     for k = 1 : size(K,1)
% %                         A(k,:) = A(k,:) + exp(-T/t2(j))*amps(k);
% %                     end
%                     A = A + amps*exp(-T/t2(j));
%                 end
%                 fid(i,:,:) = A;
%             end
%             t_register = T;
% End new vesrion
%             answer = uiconfirm(app.BEL1DUIFigure,'What data representation?','Representation','Options',{'Complex','Absolute value'},'DefaultOption',2,'Icon','question');
%             if strcmp(answer,'Complex')
%                 complex_val = true;
%             else
%                 complex_val = false;
%             end
            complex_val = false;
            if complex_val
                tmp1 = real(fid);
                tmp2 = imag(fid);
                
                tmp_global = zeros(Models.N, size(tmp1,2)*2,size(tmp1,3));
                tmp_global(:,1:end/2,:)=tmp1;
                tmp_global(:,end/2+1:end,:)=tmp2;
                
                fid = tmp_global;
            else
                fid = abs(fid);
            end
            tmp = [];
            for i = 1 : size(fid,2)
                tmp = [tmp squeeze(fid(:,i,:))]; %#ok<AGROW>
            end
            Models.Forward = tmp;
            Models.Xaxis = [t_register double(complex_val)];%Times + complex?
        end

        function Models = ForwardDC(app,Models,progress)
            % Models = app.PREBEL.Models;
            % The app.STATE.forward structure contains:
            %   forward.f_min -> The dead time of measurement
            %   forward.nbSteps -> The measurement frequency
            %   forward.f_max -> The maximum measurement time
            %   forward.f_register -> The times at each measurements
            f_compute = app.PREBEL.forward.f_register;% Frequency of datapoints
            
            tic;
            Models.Forward = zeros(Models.N,length(f_compute));
            ignored = false(1,Models.N);
            nbLayers = size(Models.param.values,1);
            if size(f_compute,1) > 1
                f_compute = f_compute';
            end
            for i = 1 : Models.N
                progress.Value = i/Models.N;
                try
                    out = gpdc([Models.models.All(i,1:nbLayers-1) 0],Models.models.All(i,2*nbLayers:3*nbLayers-1),Models.models.All(i,nbLayers:2*nbLayers-1),Models.models.All(i,3*nbLayers:end),'fV',f_compute);
                    out = out(:,2); % Only intersted in the fundamental mode (Rayleight)
                    if ~isempty(find(isnan(out),1))
                        ignored(i) = true;
                    else
                        Models.Forward(i,:) = (out').^(-1);
                    end
                catch
                    ignored(i) = true;
                end
            end
            N = Models.N;
            Models.N = sum(~ignored);
            app.NumberofpriorrealizationsEditField.Value = Models.N;
            Models.Forward = Models.Forward(~ignored,:);
            Models.models.All = Models.models.All(~ignored,:);
            Models.Xaxis = f_compute;
            elapsedTime = toc;
            if sum(ignored) > 0
                uialert(app.BEL1DUIFigure,sprintf([num2str(sum(ignored)) ' models were not computed (on ' num2str(N) '). \n CPU time: ' num2str(elapsedTime) ' seconds.']),'DC compute');
            end
        end
        
        function band_noise = NoisePropagationModelFID(~, PRE, noise_level, progress)
            % NoisePropagationModel is a function that estimates the impact of different noise levels 
            % on the different CCA dimension.
            % On this current version, the model is only available for SNMR data (Gaussian noise model).
            Prior_d = PRE.Models.Forward;
            PCA_d_base = PRE.PCA.d.score;
            coeff = PRE.PCA.d.coeff;
            A = PRE.CCA.d.coeff;
            noise_level = noise_level*1e-9;% Transforming nV in V
            
            % Getting the number of PCA dim kept:
            dimD = size(PCA_d_base,2);
            
            % Testing the impact of noise (on 50 random samples):
            nbTest = ceil(PRE.Models.N/10);
            Cov_df = zeros(nbTest,dimD,dimD);
            index = randperm(size(Prior_d,1),nbTest);%ceil(rand(1,nbTest).*size(Prior_d,1));
            warning('off','all');
            for i = 1 : nbTest
                data_real = Prior_d(index(i),:)+randn(1,size(Prior_d,2))*noise_level;
                data_synthe = Prior_d;
                % data_synthe(index(i),:) = [];
                % [coeff,~,~,~,~,~]=pca(data_synthe,'NumComponents',dimD);
                score_D_tmp = (data_real - mean(data_synthe,1))*coeff;
                score_D_tmp = score_D_tmp(1:dimD);
                Cov_df(i,:,:) = cov([PCA_d_base(index(i),:);score_D_tmp]);
                progress.Value = i/nbTest;
            end
            warning('on','all');
            
            % Initially, the function is the mean
            % C_f = squeeze(mean(Cov_df,1));
            % However, using the mean, we might give too few impact to the noise in case our dataset is strongly impacted
            % We thus use the maximum for all the different elements of C_f:
            C_f = squeeze(max(Cov_df,[],1));
            
            C_c = A'*C_f*A;
            
            band_noise = diag(C_c).^(1/2);% We are neglecting the components outside the diagonal
            
        end
    
        function band_noise = NoisePropagationModelDC(app, PRE, progress)
            % NoisePropagationModel is a function that estimates the impact of different noise levels 
            % on the different CCA dimension.
            % On this current version, the model is only available for SNMR data (Gaussian noise model).
            % Noise model from Boaga et al. (2011): +/- 0.05*V_R + 100/f (depending on phase velocity and frequency)
            Prior_d = PRE.Models.Forward;
            PCA_d_base = PRE.PCA.d.score;
            coeff = PRE.PCA.d.coeff;
            A = PRE.CCA.d.coeff;
            f =  app.PREBEL.forward.f_register;
            
            % Getting the number of PCA dim kept:
            dimD = size(PCA_d_base,2);
            
            % Testing the impact of noise (on 50 random samples):
            nbTest = ceil(PRE.Models.N/10);
            Cov_df = zeros(nbTest,dimD,dimD);
            index = randperm(size(Prior_d,1),nbTest);%ceil(rand(1,nbTest).*size(Prior_d,1));
            warning('off','all');
            for i = 1 : nbTest
                normVal = randn(1); % We select a random variation to add/substract to the curve
                data_real = Prior_d(index(i),:)+normVal.*(0.05*Prior_d(index(i),:)+f'./100);
                data_synthe = Prior_d;
                % data_synthe(index(i),:) = [];
                % [coeff,~,~,~,~,~]=pca(data_synthe,'NumComponents',dimD);
                score_D_tmp = (data_real - mean(data_synthe,1))*coeff;
                score_D_tmp = score_D_tmp(1:dimD);
                Cov_df(i,:,:) = cov([PCA_d_base(index(i),:);score_D_tmp]);
                progress.Value = i/nbTest;
            end
            warning('on','all');
            
            % Initially, the function is the mean
            % C_f = squeeze(mean(Cov_df,1));
            % However, using the mean, we might give too few impact to the noise in case our dataset is strongly impacted
            % We thus use the maximum for all the different elements of C_f:
            C_f = squeeze(max(Cov_df,[],1));
            
            C_c = A'*C_f*A;
            
            band_noise = abs(diag(C_c)).^(1/2);% We are neglecting the components outside the diagonal
            
        end
        
        
        function POST = RunBEL1D(app,PRE,nbSamp,progress)
% 0 %%%%%%%%%% Initialization %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            POST.Models = PRE.Models; % gathering all the informations from the prior models
            POST.Models.N = nbSamp; % Change the number of sampled models to correspond to current value
            POST.Models.models = []; % Deleting the prior models from the structure
            POST.Models = rmfield(POST.Models,'Forward');
% 1 %%%%%%%%%% Checking if the data is inside the prior and KDE %%%%%%%%%%           
            progress.Message = 'Checking validity of the data and prior';
            NoiseLevel = app.ValuenVEditField.Value;
            if app.NoiseCheckBox.Value
                progress.Message = 'Computing the noise model';
                if app.STATE.datatype == 1 % Free Induction Decay (SNMR)
                    noiseProp = NoisePropagationModelFID(app,PRE,NoiseLevel,progress);
                elseif app.STATE.datatype == 2 % Dispersion curve (MASW)
                    noiseProp = NoisePropagationModelDC(app,PRE,progress);
                else
                    uialert(app.BEL1DUIFigure,'No error model available','Error','Icon','error');
                    return
                end
                % Adding to the graph the noise propagation (to do!)
            end
            POST.Data.PCA = (app.POSTBEL.Data.data - PRE.PCA.d.mean)*PRE.PCA.d.coeff;
            POST.Data.CCA = (POST.Data.PCA - PRE.CCA.d.mean)*PRE.CCA.d.coeff;
            POST.Data.data = app.POSTBEL.Data.data;
            for i = 1 : PRE.CCA.d.dim
                Xaxis = PRE.KDE(i).X(1,:);
                if app.NoiseCheckBox.Value
                    if POST.Data.CCA(i) < (min(min(Xaxis))-3*noiseProp(i))|| POST.Data.CCA(i) > (max(max(Xaxis))+3*noiseProp(i))
                        uialert(app.BEL1DUIFigure,['The dataset is outside of the sampled prior (dimension ' num2str(i) ')'],'Error','Icon','error');
                        %close(progress);
                        POST = false;
                        return
                    end
                else
                    if POST.Data.CCA(i) < min(min(Xaxis)) || POST.Data.CCA(i) > max(max(Xaxis))
                        uialert(app.BEL1DUIFigure,['The dataset is outside of the sampled prior (dimension ' num2str(i) ')'],'Error','Icon','error');
                        %close(progress);
                        POST = false;
                        return
                    end
                end
                if not(app.NoiseCheckBox.Value) %isnan(app.PREBEL.NoiseModel)
                    progress.Message = 'Computing the reduced posterior';
                    if ~isempty(find(Xaxis==POST.Data.CCA(i), 1))
                        KDE_tmp = PRE.KDE(i).KDE(:,Xaxis==POST.Data.CCA(i));
                    else
                        indMin = find(Xaxis<POST.Data.CCA(i),1,'last');
                        indMax = indMin + 1;
                        KDE_tmp = PRE.KDE(i).KDE(:,indMin) + (PRE.KDE(i).KDE(:,indMax)-PRE.KDE(i).KDE(:,indMin))/PRE.KDE(i).band * (POST.Data.CCA(i)-Xaxis(indMin));
                    end
                else
                    % Inputting the noise level:
%                         if isnan(NoiseLevel)
%                             tmp = MenuNoiseSNMR;
%                             waitfor(tmp,'CloseGUI',true);
%                             NoiseLevel = tmp.Noise;
%                             delete(tmp);
%                         end
                    % Computing the posterior distribution
                    KDE_tmp = zeros(size(PRE.KDE(:,1)));
                    nbSamples = 100;
                    values = randn(1,nbSamples).*noiseProp(i)+POST.Data.CCA(i);
                    progress.Message = 'Computing the reduced posterior';
                    for j = 1 : nbSamples
                        if ~isempty(find(Xaxis==values(j), 1))
                            KDE_tmp = KDE_tmp + PRE.KDE(i).KDE(:,Xaxis==values(j));
                        else
                            indMin = find(Xaxis<values(j),1,'last');
                            if isempty(indMin)% Value is lower than the minimum
                                indMax = 1;
                                tmp1 = (PRE.KDE(i).KDE(:,indMax))/PRE.KDE(i).band * (values(j)-(Xaxis(indMax)-PRE.KDE(i).band));
                                tmp1(tmp1<0) = 0;
                                KDE_tmp = KDE_tmp + tmp1;
                            elseif indMin == length(Xaxis)
                                tmp1 = PRE.KDE(i).KDE(:,indMin) + (-PRE.KDE(i).KDE(:,indMin))/PRE.KDE(i).band * (values(j)-Xaxis(indMin));
                                tmp1(tmp1<0) = 0;
                                KDE_tmp = KDE_tmp + tmp1;
                            else
                                indMax = indMin + 1;
                                KDE_tmp = KDE_tmp + PRE.KDE(i).KDE(:,indMin) + (PRE.KDE(i).KDE(:,indMax)-PRE.KDE(i).KDE(:,indMin))/PRE.KDE(i).band * (values(j)-Xaxis(indMin));
                            end
                        end
                    end
                    KDE_tmp = KDE_tmp./nbSamples;
                end
                proba = sum(KDE_tmp);% Not normalized but OK for next step
                POST.KDE(i).CDF = cumsum(KDE_tmp./proba);
                proba = sum(KDE_tmp.*PRE.KDE(i).band);% Not normalized but OK for next step
                POST.KDE(i).KDE = KDE_tmp./proba;
                POST.KDE(i).axis = PRE.KDE(i).Y(:,1); 
            end
% 2 %%%%%%%%%% Sampling and back-transformation %%%%%%%%%%%%%%%%%%%%%%%%%%%
            progress.Message = 'Sampling posterior models';
            progress.Indeterminate = 'off';
            progress.Value = 0;
            if ~app.STATE.ModelsSamp
                type = app.PREBEL.priorType;
                if type ~= 2
                    param = app.PREBEL.Models.param.values;
                    if app.PREBEL.dataType == 2% Remove nu from parameters
                        nu = false(1,10);
                        nu([7 8]) = true;
                        param = param(:,~nu);
                    end
                    nbLayers = size(param,1);
                    nbParam = nbLayers*size(param,2)/2-1;
                    j = 1;
                    k = 1;
                    mins = zeros(1,nbParam);
                    maxs = zeros(1,nbParam);
                    for i = 1 : nbParam
                        if k > nbLayers || (j==1 && isnan(param(k,j)))
                            j = j + 2;
                            k = 1;
                        end
                        mins(i) = param(k,j);
                        maxs(i) = param(k,j+1);
                        k = k + 1;
                    end
                    condParam = '@(variable) [true ';
                    for i = 1 : size(PRE.Select.Save,2)
                        condParam = [condParam ' & variable(:,' num2str(i) ') > ' num2str(mins(i)) ' & variable(:,'...
                            num2str(i) ') < ' num2str(maxs(i))]; %#ok<AGROW>
                    end
                    condParam = [condParam ']'];
                else
                    condParam = '@(variable) [all(variable>0,2)]';% Parameters are all positive!
                end
                condParam = str2func(condParam);
            else
                condParam = app.PREBEL.Models.models.condParam;
            end
            condFun = app.PREBEL.Models.models.CondFun;
            listEqual = app.PREBEL.Models.models.listEqual;
            POST.Models.models.CondFun = condFun;
            POST.Models.models.condParam = condParam;
            POST.Models.models.listEqual = listEqual;
            POST.Models.N = app.NbPostEditField.Value;
            nbModels = POST.Models.N;
            nbParam = size(PRE.Select.Save,2);
            % Sampling
            NOTOK = true;
            modelsTmp = zeros(nbModels,nbParam);
            indice = 1;
            remains = nbModels;
            loopIter = 0;
            passed = false;
            % Classical sampler - possible conditions
            while NOTOK
                progress.Value = (nbModels-remains)/nbModels;
                % Sampling all remaining models:
                temporary = rand(remains,PRE.CCA.m.dim);
                % Transforming to the values in CCA space:
                for i = 1 : PRE.CCA.m.dim
                    for j = 1 : remains
                        idx = find(POST.KDE(i).CDF<=temporary(j,i),1,'last');
                        if isempty(idx)
                            idx = 1;
                        end
                        try
                            temporary(j,i) = POST.KDE(i).axis(idx);
                        catch
                            i
                            sizeKDE = size(POST.KDE)
                            j
                            idx
                            sizeAxis = size(POST.KDE(i).axis)
                        end
                    end
                end
                temporary = temporary*pinv(PRE.CCA.m.coeff) + repmat(PRE.CCA.m.mean,remains,1);% Return to PCA space
                temporary = temporary*pinv(PRE.PCA.m.coeff) + repmat(PRE.PCA.m.mean,remains,1);% Return to original space
                %%% Adding missing parameters from distance-based approach:
                if ~app.PREBEL.Select.All
                    savedVal = PRE.Select.Save;
                    InitVal = savedVal(:,PRE.Select.Var);
                    temporaryLarge = zeros(size(temporary,1),size(savedVal,2));
                    temporaryLarge(:,PRE.Select.Var) = temporary;
                    distance = pdist2(InitVal,temporary,'seuclidean');
                    [~,I] = min(distance,[],1);
                    temporaryLarge(:,~PRE.Select.Var) = savedVal(I,~PRE.Select.Var);
                    temporary  = temporaryLarge;
                end
                %%% The sampled models are full now
                cond = condParam(temporary) & condFun(temporary);% If nu is a possible removed parameter -> error
                if loopIter < 1 && size(cond,1)==1
                    modelsTmp = temporary;
                    NOTOK = false;
                else
                    modelsTmp(indice:indice+sum(cond)-1,:) = temporary(cond,:);
                    indice = indice+sum(cond);
                    remains = nbModels - indice + 1;
                    if indice > nbModels
                        NOTOK = false;
                    end
                    loopIter = loopIter + 1;
                    if loopIter > 100 && ~passed
                        uialert(app.BEL1DUIFigure,'The conditions may be difficult to achieve with the prior!','Warning','Icon','warning');
                        passed = true;
                    end
                    if loopIter > 10000
                        uialert(app.BEL1DUIFigure,'Impossible to achieve the set of conditions within the prior!','Error','Icon','error');
                        close(progress);
                        return
                    end
                end
            end
            
            POST.Models.models.All = modelsTmp;
            % Adding informations about selection of parameters:
            POST.Select = PRE.Select;
            POST.Select = rmfield(POST.Select,'Save');
            POST.Select = rmfield(POST.Select,'All');
            
% end %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Start_GUI(app)
            Logo = imread('Logos_Support.jpeg');
            imshow(Logo,'parent',app.UI_Logos);
            app.PREBEL = [];
            app.POSTBEL = [];
            app.STATE.PREBEL = false;% Nothing has yet been computed
            app.STATE.POSTBEL = false;% Nothing has yet been computed
            app.STATE.datatype = 1;% Default value is SNMR
            app.STATE.priortype = 1;% Default value is Uniform
            app.STATE.nbLayers = 2;% Default value for the number of layers
            app.STATE.ModelsSamp = false;% If models loaded from postbel file
            app.STATE.ModelsFull = false;% If loaded models have a forward model
            set(app.ConditionsTable,'Visible','off');
            set(app.ConditionsSaveButton,'Visible','off');
            set(app.ConditionsLoadButton,'Visible','off');
            set(app.ConditionsInitializeButton,'Visible','off');
            set(app.NoConditionsLabel,'Visible','on');
            set(app.NoDescriptionText,'Visible','off');
            set(app.NoiseCheckBox,'Visible','off');
        end

        % Selection changed function: DataType_ButtonGroup
        function Intro_DataTypeChange(app, event)
            selectedButton = app.DataType_ButtonGroup.SelectedObject;
            text = selectedButton.Text;
            switch text
                case 'SNMR'
                    datatype = 1;
                case 'Surface Waves'
                    datatype = 2;
                otherwise
                    datatype = 3;
            end
            app.PREBEL = [];
            app.POSTBEL = [];
            app.STATE.PREBEL = false;
            app.STATE.POSTBEL = false;
            app.STATE.datatype = datatype;
            set(app.NoiseCheckBox,'Visible','off');
        end

        % Selection changed function: PriorType_ButtonGroup
        function Intro_PriorTypeChange(app, event)
            selectedButton = app.PriorType_ButtonGroup.SelectedObject;
            text = selectedButton.Text;
            switch text
                case 'Uniform'
                    priortype = 1;
                    app.PREBEL = [];
                    app.POSTBEL = [];
                    app.STATE.PREBEL = false;
                    app.STATE.ModelsSamp = false;
                    app.STATE.ModelsFull = false;
                    set(app.ModelsAndConditions,'Visible','on');
                    set(app.AddingConditionsButton,'Visible','on');
                    set(app.NoDescriptionText,'Visible','off');
                case 'Gaussian'
                    priortype = 2;
                    app.PREBEL = [];
                    app.POSTBEL = [];
                    app.STATE.PREBEL = false;
                    app.STATE.ModelsSamp = false;
                    app.STATE.ModelsFull = false;
                    set(app.ModelsAndConditions,'Visible','on');
                    set(app.AddingConditionsButton,'Visible','on');
                    set(app.NoDescriptionText,'Visible','off');
                otherwise % Models to be loaded!
                    priortype = 3;
                    % Defining PREBEL.Models from a POSTBEL file!
                    app.PREBEL = [];
                    [file, path] = uigetfile({'*.postbel','POSTBEL file (*.postbel)'},'Select postbel file');
                    postbel = load([path file],'-mat');
                    postbel = postbel.postbel;
                    app.PREBEL.Models = postbel.Models;
                    app.PREBEL.Select = postbel.Select;
                    app.STATE.ModelsSamp = true;
                    if isfield(app.PREBEL.Models,'Forward')
                         app.STATE.ModelsFull = true;
                    end
                    app.POSTBEL = [];
                    app.STATE.POSTBEL = false;
                    set(app.ModelsAndConditions,'Visible','off');
                    set(app.AddingConditionsButton,'Visible','off');
                    set(app.NoDescriptionText,'Visible','on');
                    app.NumberofpriorrealizationsEditField.Value = app.PREBEL.Models.N;
            end
            app.STATE.priortype = priortype;
        end

        % Selection change function: TabGroup
        function Change_Menu(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            text = selectedTab.Title;
            switch text
                case 'Introduction'% We are comming back to the introduction panel: Nothing appens!
%%%%%%%%% Entering the Introduction panel %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Do nothing
                case 'Preparation of BEL1D'
%%%%%%%%% Entering the PREBEL panel %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if ~app.STATE.PREBEL % If the panel has not been loaded previously
                        % Load the correct UI for the datatype/priortype
                        % 1) Detect the type of data:
                        datatype = app.STATE.datatype;
                        priortype = app.STATE.priortype;
            %%%%%%%%%% Defining the default prior model space %%%%%%%%%%%
                        switch datatype
                            case 1 % SNMR
                                if priortype ~= 2
                                    d = {true,0, 50, 0, 50, 0, 500; ...
                                        true,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500; ...
                                        false,0, 50, 0, 50, 0, 500};% Default values for the ranges
                                else
                                    d = {true,25, 10, 25, 10, 250, 75; ...
                                        true,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75; ...
                                        false,25, 10, 25, 10, 250, 75};% Default values for the ranges
                                end
                                paramNames = {'e','W','T2'};
                            case 2 % Surface Waves
                                if priortype ~= 2
                                    d = {true,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        true,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000; ...
                                        false,0, 50, 0, 500, 0, 5000, 0.2, 0.5, 1000, 3000};% Default values for the ranges
                                else
                                    d = {true,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        true,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500; ...
                                        false,25, 10, 250, 75, 2500, 750, 0.35, 0.1, 2000, 500};% Default values for the ranges
                                end
                                paramNames = {'e','Vs','Vp','nu','rho'};
                            otherwise % All other cases
                                % Select parameters names:
                                nbParam = inputdlg('Number of parameters (without thickness):');
                                nbParam = str2double(nbParam{1});
                                paramNames = {'e'};
                                if nbParam > 0
                                    prompt = {'Name of parameter 1:'};
                                    definput = {'P1'};
                                    for i = 2 : nbParam
                                        prompt{i} = ['Name of parameter ' num2str(i) ':'];
                                        definput{i} = ['P' num2str(i)];
                                    end
                                end
                                answer = inputdlg(prompt,'Parameters names',[1 35],definput);
                                for i = 1 : nbParam
                                    paramNames{i+1} = answer{i};
                                end
                                % Define default values for the parameters (0):
                                d = cell(10,(nbParam+1)*2+1);
                                for i = 1 : 10
                                    d{i,1} = (i<3);
                                    for j = 1 : (nbParam+1)*2
                                        d{i,j+1} = 0;
                                    end
                                end
                        end % End of switch datatype (default values for datatype are defined)
                        app.STATE.defaultPrior = d;
                        app.STATE.paramNames = paramNames;
                        Headers = {'Layer Concidered?'};
                        j = 2;
                        for i = 1 : length(paramNames)
                            if priortype ~= 2
                                Headers{j} = [paramNames{i} ' - min'];
                                j = j + 1;
                                Headers{j} = [paramNames{i} ' - max'];
                                j = j + 1;
                            else
                                Headers{j} = [paramNames{i} ' - mean'];
                                j = j + 1;
                                Headers{j} = [paramNames{i} ' - std'];
                                j = j + 1;
                            end
                        end
                        app.PriorModelTable.ColumnName = Headers;
                        tmp{1} = 'Layer 1';
                        for i = 2 : size(d,1)
                            tmp{i} = ['Layer ' num2str(i)]; %#ok<AGROW>
                        end
                        layers = tmp;
                        app.PriorModelTable.RowName = layers;
                        app.PriorModelTable.ColumnEditable = true;
                        app.PriorModelTable.Data = d;
            %%%%%%%%%% Defining the default condition table %%%%%%%%%%%
                        nbLayers = app.STATE.nbLayers; % Default value at initialization            
                        nbParam = (size(d,2)-1)/2;
                        k = 1;
                        for i = 1 : length(paramNames)
                            for j = 1 : nbLayers
                                if ~(isequal(paramNames{i},'e') && j == nbLayers)
                                    names{k} = [paramNames{i} '_' num2str(j)];
                                    k = k + 1;
                                end
                            end
                        end
                        nbParam = length(names);
                        data = cell(nbParam,nbParam);
                        for i = 1 : nbParam
                            for j = 1 : nbParam
                                if i == j
                                    data{i,j} = '=';
                                else
                                    data{i,j} = 'NaN';
                                end
                            end
                        end
                        app.STATE.names_layered = names;
                        app.ConditionsTable.Data = data;
                        app.STATE.defaultCond = data;
                        app.ConditionsTable.ColumnName = names;
                        app.ConditionsTable.RowName = names;
                        app.ConditionsTable.ColumnEditable = true(1,nbParam);
            %%%%%%%%%% Defining the default parameters selection table
                        app.ParametersSelectTable.Data = true(nbLayers,length(paramNames));
                        app.ParametersSelectTable.Data(end,1) = false;% The thickness parameter does not exist for the last layer (half-space)
                        app.ParametersSelectTable.ColumnName = paramNames;
                        app.ParametersSelectTable.RowName = layers(1:nbLayers);
                        app.ParametersSelectTable.ColumnEditable = true;
            %%%%%%%%%% End of initialization %%%%%%%%%%%%%%%%%%%%%%%%%%   
                        app.STATE.PREBEL = true;
                    end % End of initialization of the prebel panel
                otherwise
%%%%%%%%% Entering the POSTBEL panel %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    app.ValuenVEditField.Visible = 'off';
                    app.ValuenVEditFieldLabel.Visible = 'off';
                    app.NoiseCheckBox.Value = false;
                    app.IterationsEditField.Visible = 'off';
                    app.IterationsEditFieldLabel.Visible = 'off';
                    app.IterateCheckBox.Value = false;
                    if ~app.STATE.POSTBEL
                        % Load the correct UI for the datatype/priortype
                        if isempty(app.PREBEL)
                            uialert(app.BEL1DUIFigure,'Please load a ''*.prebel'' file!','Warning','Icon','warning');
                            if app.STATE.datatype ~= 3
                                app.NoiseCheckBox.Visible = 'on';
                            end
                        else
                            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Initializing POSTBEL panel','Indeterminate','on');
                            hold(app.KDEAxis,'off');
                            if app.PREBEL.CCA.m.dim > 1
                                app.CCAdimSpinner.Visible = 'on';
                                app.CCAdimSpinner.Limits = [1, app.PREBEL.CCA.m.dim];
                            else
                                app.CCAdimSpinner.Value = 1;
                                app.CCAdimSpinner.Visible = 'off';
                            end
                            imagesc(app.KDEAxis,app.PREBEL.KDE(1).X(1,:),app.PREBEL.KDE(1).Y(:,1)',app.PREBEL.KDE(1).KDE);
                            title(app.KDEAxis,'Kernel Density estimation: CCA dimension 1');
                            axis(app.KDEAxis,'tight');
                            close(progress);
                            if app.STATE.datatype ~= 3
                                app.NoiseCheckBox.Visible = 'on';
                            end
                        end
                        app.STATE.POSTBEL = true;
                        app.POSTBEL.Data = [];
                        app.POSTBEL.Models = [];
                    elseif isempty(app.PREBEL)
                        uialert(app.BEL1DUIFigure,'Please load a ''*.prebel'' file!','Warning','Icon','warning');
                        app.STATE.POSTBEL = true;
                        app.POSTBEL.Data = [];
                        app.POSTBEL.Models = [];
                        if app.STATE.datatype ~= 3
                            app.NoiseCheckBox.Visible = 'on';
                        end
                    end
            end
        end

        % Close request function: BEL1DUIFigure
        function Close_GUI(app, event)
            selection = uiconfirm(app.BEL1DUIFigure,'Are you sure you want to close?','Confirm Close','Options',{'Yes','Cancel'},...
                'DefaultOption',2,'CancelOption',2,'Icon','warning');
            if strcmp(selection,'Yes')
                delete(app);
            end
        end

        % Value changed function: AddingConditionsButton
        function AddingConditions_PREBEL(app, event)
            value = app.AddingConditionsButton.Value;
            if value % Adding conitions enabled
                set(app.ConditionsTable,'Visible','on');
                set(app.ConditionsSaveButton,'Visible','on');
                set(app.ConditionsLoadButton,'Visible','on');
                set(app.ConditionsInitializeButton,'Visible','on');
                set(app.NoConditionsLabel,'Visible','off');
                app.ConditionsTable.ColumnEditable = true(1,size(app.ConditionsTable.Data,2));
            else
                set(app.ConditionsTable,'Visible','off');
                set(app.ConditionsSaveButton,'Visible','off');
                set(app.ConditionsLoadButton,'Visible','off');
                set(app.ConditionsInitializeButton,'Visible','off');
                set(app.NoConditionsLabel,'Visible','on');
            end
        end

        % Cell edit callback: PriorModelTable
        function PriorModelTableCellEdit(app, event)
            if event.Indices(2) == 1 % Changing the number of layers
                %% Adding a layer (maximum defined by table)
                if event.NewData
                    if app.PriorModelTable.Data{event.Indices(1)-1,1}% Previous layer already selected
                        % Accept the modification:
                        app.STATE.nbLayers = app.STATE.nbLayers+1;
                        d = app.PriorModelTable.Data;
                        paramNames = app.STATE.paramNames;
                        nbLayers = app.STATE.nbLayers; % Default value at initialization            
                        k = 1;
                        for i = 1 : length(paramNames)
                            for j = 1 : nbLayers
                                if ~(isequal(paramNames{i},'e') && j == nbLayers)
                                    names{k} = [paramNames{i} '_' num2str(j)];
                                    k = k + 1;
                                end
                            end
                        end
                        nbParam = length(names);
                        data = cell(nbParam,nbParam);
                        for i = 1 : nbParam
                            for j = 1 : nbParam
                                if i == j
                                    data{i,j} = '=';
                                else
                                    data{i,j} = 'NaN';
                                end
                            end
                        end
                        app.STATE.names_layered = names;
                        app.ConditionsTable.Data = data;
                        app.STATE.defaultCond = data;
                        app.ConditionsTable.ColumnName = names;
                        app.ConditionsTable.RowName = names;
                        app.ConditionsTable.ColumnEditable = true(1,nbParam);
                    %%%%%%%%%% Defining the default parameters selection table
                        app.ParametersSelectTable.Data = true(nbLayers,length(paramNames));
                        app.ParametersSelectTable.Data(end,1) = false;% The thickness parameter does not exist for the last layer (half-space)
                        app.ParametersSelectTable.ColumnName = paramNames;
                        layers{1} = 'Layer 1';
                        for i = 2 : nbLayers
                            layers{i} = ['Layer ' num2str(i)];
                        end
                        app.ParametersSelectTable.RowName = layers;
                        app.ParametersSelectTable.ColumnEditable = true;
                    %%%%%%%%%% End of initialization %%%%%%%%%%%%%%%%%%%%%%%%%%
                    else
                        app.PriorModelTable.Data{event.Indices(1),1} = false;
                    end
                else % Removing a layer (minimum = 1 -> infering mean parameters along the wholde depth!)
                    if ~app.PriorModelTable.Data{event.Indices(1)+1,1} && app.STATE.nbLayers > 1% Next layer already unselected
                        % Accept the modification:
                        app.STATE.nbLayers = app.STATE.nbLayers-1;
                        d = app.PriorModelTable.Data;
                        paramNames = app.STATE.paramNames;
                        nbLayers = app.STATE.nbLayers; % Default value at initialization            
                        k = 1;
                        for i = 1 : length(paramNames)
                            for j = 1 : nbLayers
                                if ~(isequal(paramNames{i},'e') && j == nbLayers)
                                    names{k} = [paramNames{i} '_' num2str(j)];
                                    k = k + 1;
                                end
                            end
                        end
                        nbParam = length(names);
                        data = cell(nbParam,nbParam);
                        for i = 1 : nbParam
                            for j = 1 : nbParam
                                if i == j
                                    data{i,j} = '=';
                                else
                                    data{i,j} = 'NaN';
                                end
                            end
                        end
                        app.STATE.names_layered = names;
                        app.STATE.defaultCond = data;
                        app.ConditionsTable.Data = data;
                        app.ConditionsTable.ColumnName = names;
                        app.ConditionsTable.RowName = names;
                        app.ConditionsTable.ColumnEditable = true(1,nbParam);
                    %%%%%%%%%% Defining the default parameters selection table
                        app.ParametersSelectTable.Data = true(nbLayers,length(paramNames));
                        app.ParametersSelectTable.Data(end,1) = false;% The thickness parameter does not exist for the last layer (half-space)
                        app.ParametersSelectTable.ColumnName = paramNames;
                        layers{1} = 'Layer 1';
                        for i = 2 : nbLayers
                            layers{i} = ['Layer ' num2str(i)];
                        end
                        app.ParametersSelectTable.RowName = layers;
                        app.ParametersSelectTable.ColumnEditable = true;
                    %%%%%%%%%% End of initialization %%%%%%%%%%%%%%%%%%%%%%%%%%
                    else
                        app.PriorModelTable.Data{event.Indices(1),1} = true;
                    end
                end
            end
        end

        % Cell edit callback: ConditionsTable
        function ConditionsTableCellEdit(app, event)
            indice = event.Indices;
            data_edit = event.NewData;
            switch data_edit
                case '>'
                    reverse = '<';
                case '<'
                    reverse = '>';
                case '='
                    reverse = '=';
                otherwise
                    reverse = 'NaN';
                    app.ConditionsTable.Data{indice(1),indice(2)} = 'NaN';
            end
            app.ConditionsTable.Data{indice(2),indice(1)} = reverse;
        end

        % Button pushed function: PriorInitializeButton
        function PriorInitializeButtonPushed(app, event)
            app.PriorModelTable.Data = app.STATE.defaultPrior;% Reloading the default value for the model space
        end

        % Button pushed function: PriorLoadButton
        function PriorLoadButtonPushed(app, event)
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Loading from file','Indeterminate','on');
            uit = app.PriorModelTable;
            paraNames = app.STATE.paramNames;
            type = app.STATE.priortype;
            [file, path] = uigetfile('*.model','Select the model file');
            fid = fopen([path file], 'r');
            headersLine = fgetl(fid);
            fclose(fid);
            headersLine = headersLine(~isspace(headersLine));
            if type ~= 2
                min = strfind(headersLine,'min');
                max = strfind(headersLine,'max');
            else
                min = strfind(headersLine,'mean');
                max = strfind(headersLine,'std');
            end
            if isempty(min)
                uialert(app.BEL1DUIFigure,'The file does not correspond to the distribution type!','Error','Icon','Error');
                close(progress)
                return
            end
            paramNamesFile{1} = headersLine(6:min(1)-1);
            if length(min) > 1
                for i = 2 :  length(min)
                    paramNamesFile{i} = headersLine(max(i-1)+3:min(i)-1); %#ok<AGROW>
                end
            end
            if ~isempty(paraNames)% Check if same parameters as previously inputed
                if ~isequal([paramNamesFile{:}],[paraNames{:}])
                    uialert(app.BEL1DUIFigure,'The model file you are trying to load does not correspond to the one already implemented!','Error','Icon','error');
                    close(progress);
                    return
                end
            else
                paramNames = paramNamesFile;
                Headers = {'Layer Concidered?'};
                j = 2;
                for i = 1 : length(paramNames)
                    if type ~= 2
                        Headers{j} = [paramNames{i} ' min'];
                        j = j + 1;
                        Headers{j} = [paramNames{i} ' max'];
                        j = j + 1;
                    else
                        Headers{j} = [paramNames{i} ' mean'];
                        j = j + 1;
                        Headers{j} = [paramNames{i} ' std'];
                        j = j + 1;
                    end
                end
                uit.ColumnName = Headers;
            end
            data = importdata([path file],'\t',1);
            data = num2cell(data.data);
            for i = 1 : 10
                data{i,1} = logical(data{i,1});
            end
            uit.Data = data;
            % Updating the condition and parameters selection panels:
            app.STATE.nbLayers = sum([data{:,1}]);
            paramNames = app.STATE.paramNames;
            nbLayers = app.STATE.nbLayers; % Default value at initialization            
            k = 1;
            for i = 1 : length(paramNames)
                for j = 1 : nbLayers
                    if ~(isequal(paramNames{i},'e') && j == nbLayers)
                        names{k} = [paramNames{i} '_' num2str(j)];
                        k = k + 1;
                    end
                end
            end
            nbParam = length(names);
            data = cell(nbParam,nbParam);
            for i = 1 : nbParam
                for j = 1 : nbParam
                    if i == j
                        data{i,j} = '=';
                    else
                        data{i,j} = 'NaN';
                    end
                end
            end
            app.STATE.names_layered = names;
            app.ConditionsTable.Data = data;
            app.STATE.defaultCond = data;
            app.ConditionsTable.ColumnName = names;
            app.ConditionsTable.RowName = names;
            app.ConditionsTable.ColumnEditable = true(1,nbParam);
        %%%%%%%%%% Defining the default parameters selection table
            app.ParametersSelectTable.Data = true(nbLayers,length(paramNames));
            app.ParametersSelectTable.Data(end,1) = false;% The thickness parameter does not exist for the last layer (half-space)
            app.ParametersSelectTable.ColumnName = paramNames;
            layers{1} = 'Layer 1';
            for i = 2 : nbLayers
                layers{i} = ['Layer ' num2str(i)];
            end
            app.ParametersSelectTable.RowName = layers;
            app.ParametersSelectTable.ColumnEditable = true;
        %%%%%%%%%% End of initialization %%%%%%%%%%%%%%%%%%%%%%%%%%
            close(progress);
        end

        % Button pushed function: PriorSaveButton
        function PriorSaveButtonPushed(app, event)
            uit = app.PriorModelTable;
            paraNames = app.STATE.paramNames;
            type = app.STATE.priortype;
            d = uit.Data;
            [nbLayer, nbParam] = size(d);
            Headers = {'Layer'};
            j = 2;
            for i = 1 : length(paraNames)
                if type ~= 2
                    Headers{j} = [paraNames{i} ' min'];
                    j = j + 1;
                    Headers{j} = [paraNames{i} ' max'];
                    j = j + 1;
                else
                    Headers{j} = [paraNames{i} ' mean'];
                    j = j + 1;
                    Headers{j} = [paraNames{i} ' std'];
                    j = j + 1;
                end
            end
            [file, path] = uiputfile('*.model','Select file to save');
            [fid,error] = fopen([path file],'w');
            if ~isempty(error)
                uialert(app.BEL1DUIFigure,'Unable to create/overwrite file','Error','Icon','error');
                return
            end
            for i = 1 : nbParam
                fprintf(fid,'%s\t',Headers{i});
            end
            fprintf(fid,'\n');
            for i = 1 : nbLayer
                for j = 1 : nbParam
                    if j == 1
                        fprintf(fid,'%d\t',d{i,j});
                    else
                        fprintf(fid,'%f\t',d{i,j});
                    end
                end
                fprintf(fid,'\n');
            end
            fclose(fid);
        end

        % Button pushed function: ConditionsInitializeButton
        function ConditionsInitializeButtonPushed(app, event)
            app.ConditionsTable.Data = app.STATE.defaultCond;
        end

        % Button pushed function: ConditionsLoadButton
        function ConditionsLoadButtonPushed(app, event)
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Loading from file','Indeterminate','on');
            uit = app.ConditionsTable;
            paramNames = app.STATE.names_layered;
            [file, path] = uigetfile('*.cond','Select the model file');
            fid = fopen([path file], 'r');
            headersLine = fgetl(fid);
            fclose(fid);
            spaces = isspace(headersLine);
            spaces = find(spaces);
            j = 1;
            for i = 1 : length(spaces)-1
                if spaces(i+1) ~= spaces(i)+1
                    paramNamesFile{j} = headersLine(spaces(i)+1:spaces(i+1)-1); %#ok<AGROW>
                    j = j + 1;
                end
            end
            paramNamesFile{j} = headersLine(spaces(i+1)+1:end);
            % Check if same parameters as previously inputed
            if ~isequal([paramNamesFile{:}],[paramNames{:}])
                uialert(app.BEL1DUIFigure,'The conditions file you are trying to load does not correspond to the one already implemented!','Error','Icon','error');
                close(progress);
                return
            end
            nbParam = length(paramNames);
            format = '%s\t';
            for i = 1 : nbParam - 1
                format = [format '%s\t']; %#ok<AGROW>
            end
            format = [format '%s\n'];
            fid = fopen([path file], 'r');
            data = textscan(fid,format);
            fclose(fid);
            d = cell(nbParam,nbParam);
            for i = 1 : nbParam
                for j = 1 : nbParam
                    d{i,j} = data{j+1}{i+1};
                end
            end
            uit.Data = d;
            close(progress);
        end

        % Button pushed function: ConditionsSaveButton
        function ConditionsSaveButtonPushed(app, event)
            uit = app.ConditionsTable;
            paramNames = app.STATE.names_layered;
            d = uit.Data;
            nbParam = length(paramNames);
            [file, path] = uiputfile('*.cond','Select file to save');
            [fid, error] = fopen([path file],'w');
            if ~isempty(error)
                uialert(app.BEL1DUIFigure,'Unable to create/overwrite file','Error','Icon','error');
                return
            end
            fprintf(fid,'\t');
            for i = 1 : nbParam-1
                fprintf(fid,'%s\t',paramNames{i});
            end
            fprintf(fid,'%s\n',paramNames{i+1});
            for i = 1 : nbParam
                fprintf(fid,'%s\t',paramNames{i});
                for j = 1 : nbParam-1
                    fprintf(fid,'%s\t',d{i,j});
                end
                fprintf(fid,'%s\n',d{i,j+1});
            end
            fclose(fid);
        end

        % Button pushed function: LoadingparametersButton
        function LoadingparametersButtonPushed(app, event)
            % Function that loads the parameters for the forward modelling
            d = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Loading the measurements parameters','Indeterminate','on');
            app.STATE.forward = [];
            switch app.STATE.datatype
                case 1 % SNMR
                    % Need to know:
                    % 1) Kernel file -> load Kernel file
                    % 2) Registration timing -> Either manual or from data file
                    tmp = SNMR_Exp_Design;
                    waitfor(tmp,'CloseGUI',true);
                    Forward = tmp.Forward;
                    delete(tmp);
                    app.STATE.forward = Forward;
                    % The Forward structure contains:
                    %   Forward.t_dead -> The dead time of measurement
                    %   Forward.frequency -> The measurement frequency
                    %   Forward.t_max -> The maximum measurement time
                    %   Forward.t_register -> The times at each measurements
                    %   Forward.kernel -> the kernel file (kdata structure from *.mrsk file)
                    app.STATE.forward.func = @(appData,Models,progress) ForwardFID(appData,Models,progress);
                case 2 % Surface Waves
                    tmp = SurfaceWaves_Exp_Design;
                    waitfor(tmp,'CloseGUI',true);
                    Forward = tmp.Forward;
                    delete(tmp);
                    app.STATE.forward = Forward;
                    % The Forward structure contains:
                    %   Forward.f_min -> The dead time of measurement
                    %   Forward.nbSteps -> The measurement frequency
                    %   Forward.f_max -> The maximum measurement time
                    %   Forward.f_register -> The times at each measurements
                    app.STATE.forward.func = @(appData,Models,progress) ForwardDC(appData,Models,progress);
                case 3 % Others
                    % The Forward function must contain the experimental design!
                    tmp = Others_Exp_Design;
                    waitfor(tmp,'CloseGUI',true);
                    Forward = tmp.Forward;
                    delete(tmp);
                    app.STATE.forward = Forward;
                    % The Forward structure contains:
                    %   Forward.func -> a function handle to the forward model
            end
            close(d);
        end

        % Button pushed function: RunBEL1DPreparationButton
        function RunBEL1DPreparationButtonPushed(app, event)
% 0 %%%%%%%%%% Checking if all the conditions are there %%%%%%%%%%%%%%%%
            if ~isfield(app.STATE,'forward') % The acquisition parameters are not defined!
                uialert(app.BEL1DUIFigure,'You first need to load the aquistion parameters!','Error');
                return
            end
% 1 %%%%%%%%%% Initializing the PREBEL structure %%%%%%%%%%%%%%%%%%%%%%%
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Initializing . . .','Indeterminate','on');
            if ~app.STATE.ModelsSamp
                app.PREBEL = [];
            end
            app.PREBEL.forward = app.STATE.forward;
            app.PREBEL.dataType = app.STATE.datatype; % Type of data
            app.PREBEL.forward.fun = app.STATE.forward.func;% Forward Modelling Function
            app.PREBEL.priorType = app.STATE.priortype; % Type of prior model space (uniform/gaussian)
            if ~app.STATE.ModelsSamp
                app.PREBEL.Models = []; % Structure containing the models and their characteristics
                app.PREBEL.Models.N = app.NumberofpriorrealizationsEditField.Value; % Number of prior realizations
                app.PREBEL.Models.nbLayers = app.STATE.nbLayers; % Number of layers in the models
                taille = size(app.PriorModelTable.Data);
                tmp = zeros(taille);
                for i = 1 : taille(1)
                    for j = 1 : taille(2)
                        tmp(i,j) = double(app.PriorModelTable.Data{i,j});
                    end
                end             
                tmp = tmp(1:app.PREBEL.Models.nbLayers,2:end);
                tmp(app.PREBEL.Models.nbLayers,1:2) = NaN;
                app.PREBEL.Models.param.values = tmp; % Definition of the prior model space
                app.PREBEL.Models.param.names = app.STATE.names_layered; % Names of the individual parameters
                app.PREBEL.Models.param.namesGlobal = app.STATE.paramNames; % Names of the global parameters
            end
            
            app.PREBEL.PCA = [];% Structure containing the PCA informations
            
            app.PREBEL.CCA = [];% Structure containing the CCA informations
            
            app.PREBEL.KDE = [];% Structure containing the Kernel Density Estimations
            
% 2 %%%%%%%%%% Sampling the models %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            progress.Message = 'Sampling the prior realizations . . .';
            if ~app.STATE.ModelsSamp
                app.PREBEL.Models.models = SamplingMod(app,progress); % Contains the sampled models with their conditions
                if isempty(app.PREBEL.Models.models)
                    close(progress);
                    return
                end
                % If conditions on Poisson's coefficient exist:
                if app.PREBEL.dataType == 2
                    paramNames = app.PREBEL.Models.param.names;
                    indexNu = strfind(paramNames,'nu');
                    indexNotNu = cellfun('isempty',indexNu);
                    indexNu = find(not(cellfun('isempty',indexNu)));
    % Changing the conditions to remove 'nu': Loading the function into a normal string vector
                    conditions = func2str(app.PREBEL.Models.models.CondFun);
                    % Searching the indexes of the parameters 'nu'
                    % 1) Removing the references to 'nu'
                    % 2) Changing the index of the variables to coincide with new index
                    NewIndex = cumsum(indexNotNu);
                    indexFinder = cell(length(app.PREBEL.Models.param.names),1);
                    for i = 1 : length(app.PREBEL.Models.param.names)
                        indexFinder{i} = strfind(conditions,num2str(i));
                    end
                    %Find limits of conditions:
                    persand = strfind(conditions,'&');
                    bracket1 = strfind(conditions,'[');
                    bracket2 = strfind(conditions,']');
                    limitsCond = [bracket1 persand bracket2];
                    for i = 1 : length(indexNu)
                        while ~isempty(indexFinder{indexNu(i)})
                            % Remove the reference to nu
                            lower = find(limitsCond<indexFinder{indexNu(i)}(1),1,'last');
                            upper = find(limitsCond>indexFinder{indexNu(i)}(1),1,'first');
                            if upper == bracket2 % No need to concider the bracket1 case (always 'true' at the begining)
                                removing = lower:upper-1;
                                index = true(1,length(conditions));
                                index(removing) = false;
                                conditions = conditions(index);
                            else
                                removing = lower:upper;
                                index = true(1,length(conditions));
                                index(removing) = false;
                                conditions = conditions(index);
                            end
                            for k = 1 : length(app.PREBEL.Models.param.names)
                                indexFinder{k} = strfind(conditions,num2str(k));
                            end
                        end
                    end
                    for i = 1 : length(app.PREBEL.Models.param.names)
                        indexFinder{i} = strfind(conditions,[num2str(i) ')']);
                    end
                    for i = 1 : length(app.PREBEL.Models.param.names)
                        if ~isempty(indexFinder{i})
                            l = length(num2str(i));
                            for j = 1 : length(indexFinder{i})
                                conditions(indexFinder{i}(j):indexFinder{i}(j)+l-1) = num2str(NewIndex(i),['%0' num2str(l) 'd']);
                            end
                        end
                    end
                    app.PREBEL.Models.models.CondFun = str2func(conditions);
    % Applying the condition on nu if existing (~= NaNs)
                    if ~isempty(find(isnan(app.PREBEL.Models.models.nu_1),1))% NaNs are sampled for nu
                        app.PREBEL.Models.models = rmfield(app.PREBEL.Models.models,{app.PREBEL.Models.param.names{indexNu}});
                        app.PREBEL.Models.models.All = app.PREBEL.Models.models.All(:,indexNotNu);
                        app.PREBEL.Models.param.names = app.PREBEL.Models.param.names(indexNotNu);
                        indexNu = strfind(app.PREBEL.Models.param.namesGlobal,'nu');
                        indexNotNuBis = cellfun('isempty',indexNu);
                        app.PREBEL.Models.param.namesGlobal = app.PREBEL.Models.param.namesGlobal(indexNotNuBis);
                    else % Poisson's coefficient is concidered (nu is sampled and Vs is taken accordingly)
                        indexVp = strfind(paramNames,'Vp');
                        indexVp = find(not(cellfun('isempty',indexVp))); %#ok<STRCL1>
                        indexVs = strfind(paramNames,'Vs');
                        indexVs = find(not(cellfun('isempty',indexVs))); %#ok<STRCL1>
                        for i = 1 : length(indexVs)
                            app.PREBEL.Models.models.(paramNames{indexVs(i)}) = app.PREBEL.Models.models.(paramNames{indexVp(i)}).*(((ones(app.PREBEL.Models.N,1)-app.PREBEL.Models.models.(paramNames{indexNu(i)}))./((ones(app.PREBEL.Models.N,1).*0.5)-app.PREBEL.Models.models.(paramNames{indexNu(i)}))).^(-0.5));
                        end
                        app.PREBEL.Models.models = rmfield(app.PREBEL.Models.models,{app.PREBEL.Models.param.names{indexNu}});
                        app.PREBEL.Models.models.All = app.PREBEL.Models.models.All(:,indexNotNu);
                        app.PREBEL.Models.param.names = app.PREBEL.Models.param.names(indexNotNu);
                        indexNu = strfind(app.PREBEL.Models.param.namesGlobal,'nu');
                        indexNotNuBis = cellfun('isempty',indexNu);
                        app.PREBEL.Models.param.namesGlobal = app.PREBEL.Models.param.namesGlobal(indexNotNuBis);
                    end
                end
            end
% 3 %%%%%%%%%% Running the forward models %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if ~app.STATE.ModelsFull
                if app.PREBEL.dataType ~= 3
                    progress.Message = 'Running the forward model . . .';
                    progress.Indeterminate = 'off';
                    progress.Value = 0;
                    app.PREBEL.Models = app.PREBEL.forward.fun(app,app.PREBEL.Models,progress);
                else
                    progress.Message = 'Running the forward model . . .';
                    app.PREBEL.Models = app.PREBEL.forward.fun(app.PREBEL.Models);
                end
            end
% 3bis %%%%%%% Selecting parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Parameters are sampled according to this arrangement:
            % Thickness, Param1, Param2, etc.
            % names = app.PREBEL.Models.param.names;% Individual names
            if ~isfield(app.PREBEL,'Select')
                selected = app.ParametersSelectTable.Data(:)';% List of selected parameters
                if app.STATE.datatype == 2 % If MASW, ignore 'nu'
                    nbLayers = app.STATE.nbLayers;
                    taken = true(1,nbLayers*5);% They are 54 parameters per layer by default
                    taken(nbLayers*3+1:nbLayers*4) = false;% The elements corresponding to 'nu' are removed
                    selected = selected(taken);
                end
                tmp = true(1,length(selected));
                tmp(app.PREBEL.Models.nbLayers) = false;
                selected = selected(tmp);
                app.PREBEL.Select.Var = selected;
                app.PREBEL.Select.Save = app.PREBEL.Models.models.All;
                app.PREBEL.Select.All = (length(selected) == sum(selected));
                app.PREBEL.Models.models.All = app.PREBEL.Models.models.All(:,selected);
            else
                app.PREBEL.Select.Save = app.PREBEL.Models.models.All;
                app.PREBEL.Select.All = false;
                app.PREBEL.Models.models.All = app.PREBEL.Models.models.All(:,app.PREBEL.Select.Var);
            end
% 4 %%%%%%%%%% Reducing dimensionality on the data space %%%%%%%%%%%%%%%
            progress.Indeterminate = 'on';
            progress.Message = 'Running PCA . . .';
            level_var = 0.1;
            app.PREBEL.PCA.m.score = app.PREBEL.Models.models.All - repmat(mean(app.PREBEL.Models.models.All,1),size(app.PREBEL.Models.models.All,1),1); % Centering the values on the mean
            app.PREBEL.PCA.m.mean = mean(app.PREBEL.Models.models.All,1);
            app.PREBEL.PCA.m.coeff = eye(size(app.PREBEL.Models.models.All,2));
            app.PREBEL.PCA.m.dim = size(app.PREBEL.Models.models.All,2);

            warning('off','all');
            [coeff_d,score,~,~,explained_F,~]=pca(app.PREBEL.Models.Forward);
            warning('on','all');
            tmp = cumsum(explained_F);
            nb_PC = max([find(tmp<1-level_var,1,'last'),app.PREBEL.PCA.m.dim]);% To be sure that there is at least more or equal d than h dim
            app.PREBEL.PCA.d.score = score(:,1:nb_PC);
            app.PREBEL.PCA.d.mean = mean(app.PREBEL.Models.Forward,1);
            app.PREBEL.PCA.d.coeff = coeff_d(:,1:nb_PC);
            app.PREBEL.PCA.d.dim = nb_PC;
% 5 %%%%%%%%%% Canonical correlation analysis %%%%%%%%%%%%%%%%%%%%%%%%%%
            progress.Message = 'Running CCA . . .';
            [A,B,~,Dc,Mc] = canoncorr(app.PREBEL.PCA.d.score,app.PREBEL.PCA.m.score);
            app.PREBEL.CCA.m.score = Mc;
            app.PREBEL.CCA.m.mean = mean(app.PREBEL.PCA.m.score,1);
            app.PREBEL.CCA.m.coeff = B;
            app.PREBEL.CCA.m.dim = size(B,1);
            
            app.PREBEL.CCA.d.score = Dc;
            app.PREBEL.CCA.d.mean = mean(app.PREBEL.PCA.d.score,1);
            app.PREBEL.CCA.d.coeff = A;
            app.PREBEL.CCA.d.dim = size(B,1);
% 6 %%%%%%%%%% Kernel density estimation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            progress.Message = 'Initializing KDE . . .';
            band_possible = logspace(-5,0,100);
            bandOK = ones(app.PREBEL.CCA.m.dim,1).*1e-0;
            
            for i = 1 : app.PREBEL.CCA.m.dim
                % Find the mid-point in the distributions:
                meanD = mean(app.PREBEL.CCA.d.score(:,i));
                meanM = mean(app.PREBEL.CCA.m.score(:,i));
                for j = 1 : length(band_possible)
                    % Test different bandwidths for those locations:
                    % If minimum 10 points in zone of 65% probability around mean -> OK
                    th = 0:pi/50:2*pi;
                    circleOK(:,1) = band_possible(j)*3 * cos(th) + meanD;
                    circleOK(:,2) = band_possible(j)*3 * sin(th) + meanM;
                    inside = inpolygon(app.PREBEL.CCA.d.score(:,i),app.PREBEL.CCA.m.score(:,i),circleOK(:,1),circleOK(:,2));
                    if sum(inside) > 0.05*app.PREBEL.Models.N % More than 5% of the data are inside the area of interest !
                        bandOK(i) = band_possible(j);
                        break
                    end
                end
            end
            
            % Function for computation of bivariate Gaussians:
            z = @(x,y,X,Y,band) (x-X)^2/band^2 + (y-Y)^2/band^2 - (2*(x-X)*(y-Y)/band*band);
            pdf_compute = @(x,y,X,Y,band) (1/(2*pi()*band*band)*exp(-z(x,y,X,Y,band)/2));
            th = 0:pi/50:2*pi;
            circleX = @(X,Y,r) r*cos(th) + X;
            circleY = @(X,Y,r) r*sin(th) + Y;
            
            % 6.2) Computing the KDE for each dimensions
            progress.Indeterminate = 'off';
            progress.Message = 'Running KDE . . .';
            for i = 1 : app.PREBEL.CCA.m.dim
                Dc = app.PREBEL.CCA.d.score(:,i);
                Hc = app.PREBEL.CCA.m.score(:,i);
                [X_KDE,Y_KDE] = meshgrid(min(Dc)-3*bandOK(i):bandOK(i)/2:max(Dc)+3*bandOK(i),min(Hc)-3*bandOK(i)/2:bandOK(i):max(Hc)+3*bandOK(i));
                nb_points = length(X_KDE(:));
                KDE_tmp = zeros(nb_points,1);
                for j = 1 : nb_points
                    impacts = inpolygon(Dc,Hc,circleX(X_KDE(j),Y_KDE(j),4*bandOK(i)),circleY(X_KDE(j),Y_KDE(j),4*bandOK(i)));
                    impacts = find(impacts);
                    for k = 1 : length(impacts)
                        KDE_tmp(j) = KDE_tmp(j) + pdf_compute(X_KDE(j),Y_KDE(j),Dc(impacts(k)),Hc(impacts(k)),bandOK(i));
                    end
                    if mod(j,1000)==0
                        progress.Value = (j/nb_points)*(1/app.PREBEL.CCA.m.dim)+(i-1)*(1/app.PREBEL.CCA.m.dim);
                    end
                end
                KDE_tmp = reshape(KDE_tmp,size(X_KDE,1),size(X_KDE,2));
                proba = sum(sum(KDE_tmp.*bandOK(i)^2));
                KDE_tmp = KDE_tmp./proba;
                KDE(i) = struct('X',X_KDE,'Y',Y_KDE,'KDE',KDE_tmp,'band',bandOK(i));
            end
            app.PREBEL.KDE = KDE;
% 6 bis %%%%%% Noise regression (optional/only for SNMR) %%%%%%%%%%%%%%%
%             if app.STATE.datatype == 1 % If datatype is SNMR
%                 progress.Indeterminate = 'on';
%                 progress.Message = 'Noise propagation modelling . . .';
%                 selection = uiconfirm(app.BEL1DUIFigure,'Compute noise propagation model?','Noise propagation model','Options',{'Yes','No'},'DefaultOption','Yes','Icon','question');
%                 if strcmp(selection,'Yes')
%                     nbSimul = 10;
%                     noise_levels = logspace(1,3,nbSimul);
%                     errors = zeros(size(app.PREBEL.CCA.d.coeff,2),nbSimul);
%                     count = 0;
%                     progress.Indeterminate = 'off';
%                     progress.Value = 0;
%                     for i = noise_levels
%                         count = count + 1;
%                         
%                         progress.Message = ['Simulation ' num2str(count) ' on ' num2str(nbSimul) '.'];
%                         errors(:,count) = NoisePropagationModel(app,i,progress);
%                     end
%                     figure;
%                     plot(noise_levels, errors,'o'); xlabel('Noise [nV]'); ylabel('Bandwidth [/]'); hold on;
%                     app.PREBEL.NoiseModel = noise_levels'\errors';
%                     plot(0:max(noise_levels),app.PREBEL.NoiseModel'*(0:max(noise_levels)),'k');
%                     t = 1; clear t;
%                 else
%                     app.PREBEL.NoiseModel = NaN;
%                 end
%                 
%             else
%                 app.PREBEL.NoiseModel = NaN;
%             end
% 7 %%%%%%%%%% Saving the PREBEL structure in a file (optional) %%%%%%%%
            saved = uiconfirm(app.BEL1DUIFigure,'Save pre-field operations?','Save results','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
            if strcmp(saved,'Yes')
                progress.Indeterminate = 'on';
                progress.Message = 'Saving PREBEL file';
                [file, path] = uiputfile({'*.prebel','PREBEL file (*.prebel)'},'Saving PREBEL','prebel_file.prebel');
                prebel = app.PREBEL;
                save([path file],'prebel');
                uialert(app.BEL1DUIFigure,'File saved successfully!','Success','Icon','success');
            end
            close(progress);
% end %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: LoadPREBELButton
        function LoadPREBELButtonPushed(app, event)
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Loading PREBEL file','Indeterminate','on');
            [file, path] = uigetfile({'*.prebel','PREBEL file (*.prebel)'},'Load PREBEL file');
            prebel = load([path file],'-mat');
            app.PREBEL = prebel.prebel;
            if isempty(app.PREBEL.KDE)
                uialert(app.BEL1DUIFigure,'The PREBEL file you are loading does not contain the requiered informations!','Error');
                close(progress);
                return
            end
            hold(app.KDEAxis,'off');
            if app.PREBEL.CCA.m.dim > 1
                app.CCAdimSpinner.Visible = 'on';
                app.CCAdimSpinner.Limits = [1, app.PREBEL.CCA.m.dim];
            else
                app.CCAdimSpinner.Value = 1;
                app.CCAdimSpinner.Visible = 'off';
            end
            imagesc(app.KDEAxis,app.PREBEL.KDE(1).X(1,:),app.PREBEL.KDE(1).Y(:,1)',app.PREBEL.KDE(1).KDE);
            title(app.KDEAxis,'Kernel Density estimation: CCA dimension 1');
            axis(app.KDEAxis,'tight');
            close(progress);
        end

        % Value changed function: CCAdimSpinner
        function CCAdimSpinnerValueChanged(app, event)
            hold(app.KDEAxis,'off');
            value = app.CCAdimSpinner.Value;
            imagesc(app.KDEAxis,app.PREBEL.KDE(value).X(1,:),app.PREBEL.KDE(value).Y(:,1)',app.PREBEL.KDE(value).KDE);
            title(app.KDEAxis,['Kernel Density estimation: CCA dimension ' num2str(value)]);
            if ~isempty(app.POSTBEL.Data)
                % Add the data
                hold(app.KDEAxis,'on');
                ylimits = get(app.KDEAxis,'YLim');
                plot(app.KDEAxis,repmat(app.POSTBEL.Data.CCA(app.CCAdimSpinner.Value),1,2),ylimits,'Color','r','LineWidth',2);
            end
            axis(app.KDEAxis,'tight');
        end

        % Button pushed function: LoadFieldDataButton
        function LoadFieldDataButtonPushed(app, event)
            if isempty(app.PREBEL) || ~isfield(app.PREBEL,'dataType')
                uialert(app.BEL1DUIFigure,'Please load PREBEL file first!','Warning','Icon','warning');
            else
                progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Loading data file','Indeterminate','on');
                dataType = app.PREBEL.dataType;
                switch dataType
                    case 1 % SNMR data expected
                        [file, path] = uigetfile({'*.mrsd','MRSmatlab data file (*.mrsd)'},'Select the data file');
                        [~,~,ext] = fileparts([path file]);
                        if ~strcmp(ext,'.mrsd')
                            uialert(app.BEL1DUIFigure,'The data file not correspond to SNMR data!','Error','Icon','error');
                            close(progress);
                            return
                        end
                        proclog = load([path file],'-mat');
                        proclog = proclog.proclog;
                        t_register = [proclog.Q(1).rx(1).sig(2).t]+proclog.Q(1).timing.tau_dead1;
                        if max(abs(t_register-app.PREBEL.Models.Xaxis(1:end-1))) > eps
                            uialert(app.BEL1DUIFigure,'The data does not coïncide with the prior used!','Error','Icon','error');
                            close(progress);
                            return
                        end
                        taille = size([proclog.Q(:).q],2);
                        fid = zeros(taille,length(t_register));
                        for i = 1 : taille
                            fid(i,:) = proclog.Q(i).rx(1).sig(2).V;
                        end
                        if logical(app.PREBEL.Models.Xaxis(end))% Complex data representation
                            tmp1 = real(fid);
                            tmp2 = imag(fid);
                            tmp_global = zeros(taille*2,length(t_register));
                            tmp_global(1:end/2,:)=tmp1;
                            tmp_global(end/2+1:end,:)=tmp2;
                            fid = tmp_global;
                        else % Absolute value data representation
                            fid = abs(fid);
                        end
                        tmp = [];
                        for i = 1 : size(fid,1)
                            tmp = [tmp squeeze(fid(i,:))]; %#ok<AGROW>
                        end
                        app.POSTBEL.Data.data = tmp;
                    case 2 % MASW data expected
                        [file ,path] = uigetfile({'*.maswd','MATLAB MASW data file (*.maswd)';'*','Geopsy file (no extension)'},'Select a data file');
                        [~,~,ext] = fileparts([path file]);
                        if strcmp(ext,'.maswd') % Matlab generated file
                            dispersion = load([path file],'-mat');
                            Data = dispersion.dispersion;
                        elseif strcmp(ext,'') % Geopsy generated file
                            fileID = fopen([path file],'r');
                            c = textscan(fileID, '%f %f %f %f','Delimiter',' ','CommentStyle','#');
                            fclose(fileID);
                            dispersion.Curve.f = c{1};
                            dispersion.Curve.ct = [c{2}].^(-1);
                            dispersion.Curve.lambda = dispersion.Curve.ct./dispersion.Curve.f;
                            Data = dispersion;
                        else
                            uialert(app.BEL1DUIFigure,'The file you selected is not readable by the GUI!','Error','Icon','error');
                            close(progress);
                            return
                        end
                        if size(Data.Curve.f,1)> 1
                            Data.Curve.f = Data.Curve.f';
                        end
                        if ~isequal(Data.Curve.f,app.PREBEL.Models.Xaxis)
                            uialert(app.BEL1DUIFigure,'The data does not coïncide with the prior used!','Warning','Icon','warning');
                            % We interpolate the data to cover the right span:
                            pp = interp1(Data.Curve.f,Data.Curve.ct,'linear','pp');
                            figure;
                            plot(app.PREBEL.Models.Xaxis,ppval(pp,app.PREBEL.Models.Xaxis));
                            hold on;
                            plot(Data.Curve.f,Data.Curve.ct,'d');
                            Data.Curve.f = app.PREBEL.Models.Xaxis;
                            Data.Curve.ct = ppval(pp,Data.Curve.f);
%                             close(progress);
%                             return
                        end
                        app.POSTBEL.Data.data = Data.Curve.ct';
                    otherwise % Data matrix expected
                        [file ,path] = uigetfile({'*.mat','MATLAB data file (*.mat)'},'Select a data file');
                        Data = load([path file]);% The file MUST contain a 'data' matrix !!!
                        Data = Data.data;
                        if ~isequal(Data(:,1),app.PREBEL.Models.Xaxis)
                            uialert(app.BEL1DUIFigure,'The data does not coïncide with the prior used!','Error','Icon','error');
                            close(progress);
                            return;
                        end
                        app.POSTBEL.Data.data = Data(:,2)';
                end
                app.POSTBEL.Data.PCA = (app.POSTBEL.Data.data - app.PREBEL.PCA.d.mean)*app.PREBEL.PCA.d.coeff;
                app.POSTBEL.Data.CCA = (app.POSTBEL.Data.PCA - app.PREBEL.CCA.d.mean)*app.PREBEL.CCA.d.coeff;
                % Checking that the CCA space is inside the KDE results:
                for i = 1 : app.PREBEL.CCA.d.dim
                    if app.POSTBEL.Data.CCA(i) < app.PREBEL.KDE(i).X(1,1) || app.POSTBEL.Data.CCA(i) > app.PREBEL.KDE(i).X(1,end)
                        % Producing a file that will be used to reduce the dimensionality (remove unsensitive parameters)
                        Loads = app.PREBEL.PCA.m.coeff*app.PREBEL.CCA.m.coeff;%The relative loads of all parameters dimensions (columns).
                        [~,Removable] = max(abs(Loads(:,i)));% Find the most contributing parameter
                        usedVar = find(app.PREBEL.Select.Var);% Index of used variables
                        app.PREBEL.Select.Var(usedVar(Removable)) = false;% Mark value as unused
                        uialert(app.BEL1DUIFigure,'The data is outside the kernel density space! Consider the effect of noise!','Warning');
                        app.NoiseCheckBox.Value = true;
                        if app.PREBEL.dataType == 1
                            app.ValuenVEditFieldLabel.Visible = 'on';
                        end
                        app.ValuenVEditField.Visible = 'on';
                        % Copying the PREBEL file into a POSTBEL one to provide as "iteration"
%                         postbel.Models = app.PREBEL.Models;
%                         if ~app.PREBEL.Select.All
%                             postbel.Models.models.All = app.PREBEL.Select.Save;
%                         end
%                         postbel.Data = app.POSTBEL.Data;
%                         postbel.KDE = [];% Not computed
%                         postbel.Select = app.PREBEL.Select;
%                         app.PREBEL = [];
%                         [file, path] = uiputfile('*.postbel','Select a file','Rerun.postbel');
%                         save([path file],'postbel','-mat');
%                         % End producing the file
%                         uialert(app.BEL1DUIFigure,sprintf('Try to run the process back with the created file as input.\nParameter %s was removed from the prediction space.',postbel.Models.param.names{usedVar(Removable)}),'Proposition','Icon','info');
%                         return
                    end
                end
                % End checking that inside the configuration
                hold(app.KDEAxis,'on');
                ylimits = get(app.KDEAxis,'YLim');
                plot(app.KDEAxis,repmat(app.POSTBEL.Data.CCA(app.CCAdimSpinner.Value),1,2),ylimits,'Color','r','LineWidth',2);
            end
            close(progress);
        end

        % Button pushed function: RunBEL1DButton
        function RunBEL1DButtonPushed(app, event)
            if isempty(app.PREBEL)
                uialert(app.BEL1DUIFigure,'Please load PREBEL file first!','Warning','Icon','warning');
            elseif isempty(app.POSTBEL.Data)
                uialert(app.BEL1DUIFigure,'Please load field data file first!','Warning','Icon','warning');
            else                
                progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Initializing BEL1D','Indeterminate','on');
                if not(app.IterateCheckBox.Value)
                    PRE = app.PREBEL;
                    nbSamp = app.NbPostEditField.Value;
                    app.POSTBEL = RunBEL1D(app,PRE,nbSamp,progress);
% end %%%%%%%%%% Finalizing and saving %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    progress.Message = 'Finalizing';
                    progress.Indeterminate = 'on';
                    saved = uiconfirm(app.BEL1DUIFigure,'Save BEL1D results?','Save results','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                    if strcmp(saved,'Yes')
                        progress.Message = 'Saving POSTBEL file';
                        [file, path] = uiputfile({'*.postbel','PREBEL file (*.postbel)'},'Saving POSTBEL','postbel_file.postbel');
                        postbel = app.POSTBEL;
                        save([path file],'postbel');
                        uialert(app.BEL1DUIFigure,'File saved successfully!','Success','Icon','success');
                    end
                    close(progress);
%         % 0 %%%%%%%%%% Initialization %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     app.POSTBEL.Models = app.PREBEL.Models; % gathering all the informations from the prior models
%                     app.POSTBEL.Models.models = []; % Deleting the prior models from the structure
%                     app.POSTBEL.Models = rmfield(app.POSTBEL.Models,'Forward');
%         % 1 %%%%%%%%%% Checking if the data is inside the prior and KDE %%%%%%%%%%           
%                     progress.Message = 'Checking validity of the data and prior';
%                     NoiseLevel = app.ValuenVEditField.Value;
%                     if app.NoiseCheckBox.Value
%                         progress.Message = 'Computing the noise model';
%                         % values = randn(1,nbSamples).*(app.PREBEL.NoiseModel(i)*NoiseLevel)+app.POSTBEL.Data.CCA(i);
%                         noiseProp = NoisePropagationModel(app,NoiseLevel,progress);
%                         % Adding to the graph the noise propagation (to do!)
%                     end
%                     for i = 1 : app.PREBEL.CCA.d.dim
%                         Xaxis = app.PREBEL.KDE(i).X(1,:);
%                         if app.NoiseCheckBox.Value
%                             if app.POSTBEL.Data.CCA(i) < (min(min(Xaxis))-3*noiseProp(i))|| app.POSTBEL.Data.CCA(i) > (max(max(Xaxis))+3*noiseProp(i))
%                                 uialert(app.BEL1DUIFigure,['The dataset is outside of the sampled prior (dimension ' num2str(i) ')'],'Error','Icon','error');
%                                 close(progress);
%                                 return
%                             end
%                         else
%                             if app.POSTBEL.Data.CCA(i) < min(min(Xaxis)) || app.POSTBEL.Data.CCA(i) > max(max(Xaxis))
%                                 uialert(app.BEL1DUIFigure,['The dataset is outside of the sampled prior (dimension ' num2str(i) ')'],'Error','Icon','error');
%                                 close(progress);
%                                 return
%                             end
%                         end
%                         if not(app.NoiseCheckBox.Value) %isnan(app.PREBEL.NoiseModel)
%                             progress.Message = 'Computing the reduced posterior';
%                             if ~isempty(find(Xaxis==app.POSTBEL.Data.CCA(i), 1))
%                                 KDE_tmp = app.PREBEL.KDE(i).KDE(:,Xaxis==app.POSTBEL.Data.CCA(i));
%                             else
%                                 indMin = find(Xaxis<app.POSTBEL.Data.CCA(i),1,'last');
%                                 indMax = indMin + 1;
%                                 KDE_tmp = app.PREBEL.KDE(i).KDE(:,indMin) + (app.PREBEL.KDE(i).KDE(:,indMax)-app.PREBEL.KDE(i).KDE(:,indMin))/app.PREBEL.KDE(i).band * (app.POSTBEL.Data.CCA(i)-Xaxis(indMin));
%                             end
%                         else
%                             % Inputting the noise level:
%     %                         if isnan(NoiseLevel)
%     %                             tmp = MenuNoiseSNMR;
%     %                             waitfor(tmp,'CloseGUI',true);
%     %                             NoiseLevel = tmp.Noise;
%     %                             delete(tmp);
%     %                         end
%                             % Computing the posterior distribution
%                             KDE_tmp = zeros(size(app.PREBEL.KDE(:,1)));
%                             nbSamples = 100;
%                             values = randn(1,nbSamples).*noiseProp(i)+app.POSTBEL.Data.CCA(i);
%                             progress.Message = 'Computing the reduced posterior';
%                             for j = 1 : nbSamples
%                                 if ~isempty(find(Xaxis==values(j), 1))
%                                     KDE_tmp = KDE_tmp + app.PREBEL.KDE(i).KDE(:,Xaxis==values(j));
%                                 else
%                                     indMin = find(Xaxis<values(j),1,'last');
%                                     if isempty(indMin)% Value is lower than the minimum
%                                         indMax = 1;
%                                         tmp1 = (app.PREBEL.KDE(i).KDE(:,indMax))/app.PREBEL.KDE(i).band * (values(j)-(Xaxis(indMax)-app.PREBEL.KDE(i).band));
%                                         tmp1(tmp1<0) = 0;
%                                         KDE_tmp = KDE_tmp + tmp1;
%                                     elseif indMin == length(Xaxis)
%                                         tmp1 = app.PREBEL.KDE(i).KDE(:,indMin) + (-app.PREBEL.KDE(i).KDE(:,indMin))/app.PREBEL.KDE(i).band * (values(j)-Xaxis(indMin));
%                                         tmp1(tmp1<0) = 0;
%                                         KDE_tmp = KDE_tmp + tmp1;
%                                     else
%                                         indMax = indMin + 1;
%                                         KDE_tmp = KDE_tmp + app.PREBEL.KDE(i).KDE(:,indMin) + (app.PREBEL.KDE(i).KDE(:,indMax)-app.PREBEL.KDE(i).KDE(:,indMin))/app.PREBEL.KDE(i).band * (values(j)-Xaxis(indMin));
%                                     end
%                                 end
%                             end
%                             KDE_tmp = KDE_tmp./nbSamples;
%                         end
%                         proba = sum(KDE_tmp);% Not normalized but OK for next step
%                         app.POSTBEL.KDE(i).CDF = cumsum(KDE_tmp./proba);
%                         proba = sum(KDE_tmp.*app.PREBEL.KDE(i).band);% Not normalized but OK for next step
%                         app.POSTBEL.KDE(i).KDE = KDE_tmp./proba;
%                         app.POSTBEL.KDE(i).axis = app.PREBEL.KDE(i).Y(:,1); 
%                     end
%         % 2 %%%%%%%%%% Sampling and back-transformation %%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     progress.Message = 'Sampling posterior models';
%                     progress.Indeterminate = 'off';
%                     progress.Value = 0;
%                     if ~app.STATE.ModelsSamp
%                         type = app.PREBEL.priorType;
%                         if type ~= 2
%                             param = app.PREBEL.Models.param.values;
%                             if app.PREBEL.dataType == 2% Remove nu from parameters
%                                 nu = false(1,10);
%                                 nu([7 8]) = true;
%                                 param = param(:,~nu);
%                             end
%                             nbLayers = size(param,1);
%                             nbParam = nbLayers*size(param,2)/2-1;
%                             j = 1;
%                             k = 1;
%                             mins = zeros(1,nbParam);
%                             maxs = zeros(1,nbParam);
%                             for i = 1 : nbParam
%                                 if k > nbLayers || (j==1 && isnan(param(k,j)))
%                                     j = j + 2;
%                                     k = 1;
%                                 end
%                                 mins(i) = param(k,j);
%                                 maxs(i) = param(k,j+1);
%                                 k = k + 1;
%                             end
%                             condParam = '@(variable) [true ';
%                             for i = 1 : size(app.PREBEL.Select.Save,2)
%                                 condParam = [condParam ' & variable(:,' num2str(i) ') > ' num2str(mins(i)) ' & variable(:,'...
%                                     num2str(i) ') < ' num2str(maxs(i))]; %#ok<AGROW>
%                             end
%                             condParam = [condParam ']'];
%                         else
%                             condParam = '@(variable) [all(variable>0,2)]';% Parameters are all positive!
%                         end
%                         condParam = str2func(condParam);
%                     else
%                         condParam = app.PREBEL.Models.models.condParam;
%                     end
%                     condFun = app.PREBEL.Models.models.CondFun;
%                     listEqual = app.PREBEL.Models.models.listEqual;
%                     app.POSTBEL.Models.models.CondFun = condFun;
%                     app.POSTBEL.Models.models.condParam = condParam;
%                     app.POSTBEL.Models.models.listEqual = listEqual;
%                     app.POSTBEL.Models.N = app.NbPostEditField.Value;
%                     nbModels = app.POSTBEL.Models.N;
%                     nbParam = size(app.PREBEL.Select.Save,2);
%                     % Sampling
%                     NOTOK = true;
%                     modelsTmp = zeros(nbModels,nbParam);
%                     indice = 1;
%                     remains = nbModels;
%                     loopIter = 0;
%                     passed = false;
%                     % Classical sampler - possible conditions
%                     while NOTOK
%                         progress.Value = (nbModels-remains)/nbModels;
%                         % Sampling all remaining models:
%                         temporary = rand(remains,app.PREBEL.CCA.m.dim);
%                         % Transforming to the values in CCA space:
%                         for i = 1 : app.PREBEL.CCA.m.dim
%                             for j = 1 : remains
%                                 idx = find(app.POSTBEL.KDE(i).CDF<=temporary(j,i),1,'last');
%                                 if isempty(idx)
%                                     idx = 1;
%                                 end
%                                 temporary(j,i) = app.POSTBEL.KDE(i).axis(idx);
%                             end
%                         end
%                         temporary = temporary*pinv(app.PREBEL.CCA.m.coeff) + repmat(app.PREBEL.CCA.m.mean,remains,1);% Return to PCA space
%                         temporary = temporary*pinv(app.PREBEL.PCA.m.coeff) + repmat(app.PREBEL.PCA.m.mean,remains,1);% Return to original space
%                         %%% Adding missing parameters from distance-based approach:
%                         if ~app.PREBEL.Select.All
%                             savedVal = app.PREBEL.Select.Save;
%                             InitVal = savedVal(:,app.PREBEL.Select.Var);
%                             temporaryLarge = zeros(size(temporary,1),size(savedVal,2));
%                             temporaryLarge(:,app.PREBEL.Select.Var) = temporary;
%                             distance = pdist2(InitVal,temporary,'seuclidean');
%                             [~,I] = min(distance,[],1);
%                             temporaryLarge(:,~app.PREBEL.Select.Var) = savedVal(I,~app.PREBEL.Select.Var);
%                             temporary  = temporaryLarge;
%                         end
%                         %%% The sampled models are full now
%                         cond = condParam(temporary) & condFun(temporary);% If nu is a possible removed parameter -> error
%                         if loopIter < 1 && size(cond,1)==1
%                             modelsTmp = temporary;
%                             NOTOK = false;
%                         else
%                             modelsTmp(indice:indice+sum(cond)-1,:) = temporary(cond,:);
%                             indice = indice+sum(cond);
%                             remains = nbModels - indice + 1;
%                             if indice > nbModels
%                                 NOTOK = false;
%                             end
%                             loopIter = loopIter + 1;
%                             if loopIter > 100 && ~passed
%                                 uialert(app.BEL1DUIFigure,'The conditions may be difficult to achieve with the prior!','Warning','Icon','warning');
%                                 passed = true;
%                             end
%                             if loopIter > 10000
%                                 uialert(app.BEL1DUIFigure,'Impossible to achieve the set of conditions within the prior!','Error','Icon','error');
%                                 close(progress);
%                                 return
%                             end
%                         end
%                     end
%                     
%                     app.POSTBEL.Models.models.All = modelsTmp;
%                     % Adding informations about selection of parameters:
%                     app.POSTBEL.Select = app.PREBEL.Select;
%                     app.POSTBEL.Select = rmfield(app.POSTBEL.Select,'Save');
%                     app.POSTBEL.Select = rmfield(app.POSTBEL.Select,'All');                
%                     
%         % 3 %%%%%%%%%% Finalizing and saving %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     progress.Message = 'Finalizing';
%                     progress.Indeterminate = 'on';
%                     saved = uiconfirm(app.BEL1DUIFigure,'Save BEL1D results?','Save results','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
%                     if strcmp(saved,'Yes')
%                         progress.Message = 'Saving POSTBEL file';
%                         [file, path] = uiputfile({'*.postbel','PREBEL file (*.postbel)'},'Saving POSTBEL','postbel_file.postbel');
%                         postbel = app.POSTBEL;
%                         save([path file],'postbel');
%                         uialert(app.BEL1DUIFigure,'File saved successfully!','Success','Icon','success');
%                     end
%         % end %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     close(progress);
                else % Iterations!!!
                    app.ITER = [];
                    app.ITER(app.IterationsEditField.Value).POSTBEL = struct([]);
                    app.ITER(app.IterationsEditField.Value).PREBEL = struct([]);
                    iter = 0;
                    while iter < app.IterationsEditField.Value
                        progress.Title = ['Please wait . . . Iteration ' num2str(iter+1) ' on ' num2str(app.IterationsEditField.Value)];
                        if iter == 0
                            PRE = app.PREBEL;
                        else
                            if isempty(app.ITER(iter).POSTBEL.Models.Forward)
                                % Compute the forward model
                                progress.Message = 'Running forward modelling . . .';
                                tic;
                                app.ITER(iter).POSTBEL.Models = app.PREBEL.forward.fun(app,app.ITER(iter).POSTBEL.Models,progress);
                                app.ITER(iter).timing.forward = toc;
                            end
                            PRE = app.ITER(iter).POSTBEL;
                            
%                             PRE = app.PREBEL;
% %                             Add models from previous iterations
%                             for i = 1 : iter
%                                 % Catch the models and compute there forward models
%                                 if isempty(app.ITER(i).POSTBEL.Models.Forward)
%                                     % Compute the forward model
%                                     app.ITER(i).POSTBEL.Models = PRE.forward.fun(app,app.ITER(i).POSTBEL.Models,progress);
%                                 end
%                                 % Append to the PRE structure
%                                 PRE.Models.models.All = [PRE.Models.models.All; app.ITER(i).POSTBEL.Models.models.All];
%                                 PRE.Models.Forward = [PRE.Models.Forward; app.ITER(i).POSTBEL.Models.Forward];                                
%                             end
                            % Run PCA, CCA and KDE back again
%%%%%% Addition on 16th of March 2020
                % 3bis %%%%%%% Selecting parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            % Parameters are sampled according to this arrangement:
                            % Thickness, Param1, Param2, etc.
                            % names = app.PREBEL.Models.param.names;% Individual names
                            if ~isfield(PRE,'Select')
                                selected = app.ParametersSelectTable.Data(:)';% List of selected parameters
                                if app.STATE.datatype == 2 % If MASW, ignore 'nu'
                                    nbLayers = app.STATE.nbLayers;
                                    taken = true(1,nbLayers*5);% They are 54 parameters per layer by default
                                    taken(nbLayers*3+1:nbLayers*4) = false;% The elements corresponding to 'nu' are removed
                                    selected = selected(taken);
                                end
                                tmp = true(1,length(selected));
                                tmp(app.PREBEL.Models.nbLayers) = false;
                                selected = selected(tmp);
                                PRE.Select.Var = selected;
                                PRE.Select.Save = PRE.Models.models.All;
                                PRE.Select.All = (length(selected) == sum(selected));
                                PRE.Models.models.All = PRE.Models.models.All(:,selected);
                            else
                                PRE.Select.Save = PRE.Models.models.All;
                                PRE.Select.All = false;
                                PRE.Models.models.All = PRE.Models.models.All(:,PRE.Select.Var);
                            end
                % 4 %%%%%%%%%% Reducing dimensionality on the data space %%%%%%%%%%%%%%%
                            progress.Indeterminate = 'on';
                            progress.Message = 'Running PCA . . .';
                            tic;
                            level_var = 0.1;
                            PRE.PCA.m.score = PRE.Models.models.All - repmat(mean(PRE.Models.models.All,1),size(PRE.Models.models.All,1),1); % Centering the values on the mean
                            PRE.PCA.m.mean = mean(PRE.Models.models.All,1);
                            PRE.PCA.m.coeff = eye(size(PRE.Models.models.All,2));
                            PRE.PCA.m.dim = size(PRE.Models.models.All,2);
                
                            warning('off','all');
                            [coeff_d,score,~,~,explained_F,~]=pca(PRE.Models.Forward);
                            warning('on','all');
                            tmp = cumsum(explained_F);
                            nb_PC = max([find(tmp<1-level_var,1,'last'),PRE.PCA.m.dim]);% To be sure that there is at least more or equal d than h dim
                            PRE.PCA.d.score = score(:,1:nb_PC);
                            PRE.PCA.d.mean = mean(PRE.Models.Forward,1);
                            PRE.PCA.d.coeff = coeff_d(:,1:nb_PC);
                            PRE.PCA.d.dim = nb_PC;
                            app.ITER(iter+1).timing.PCA = toc;
                % 5 %%%%%%%%%% Canonical correlation analysis %%%%%%%%%%%%%%%%%%%%%%%%%%
                            progress.Message = 'Running CCA . . .';
                            tic;
                            [A,B,r,Dc,Mc] = canoncorr(PRE.PCA.d.score,PRE.PCA.m.score);% r contains the correlation of each dimension!
                            PRE.CCA.m.score = Mc;
                            PRE.CCA.m.mean = mean(PRE.PCA.m.score,1);
                            PRE.CCA.m.coeff = B;
                            PRE.CCA.m.dim = size(B,1);
                            
                            PRE.CCA.d.score = Dc;
                            PRE.CCA.d.mean = mean(PRE.PCA.d.score,1);
                            PRE.CCA.d.coeff = A;
                            PRE.CCA.d.dim = size(B,1);
                            app.ITER(iter+1).timing.CCA = toc;
                            
                            % If all the correlations are good, we do not need as many models sampled -> divide by 2 the sampled models
                            if mean(abs(r)) > 0.8 % Significant correlation for all dimensions
                                app.NbPostEditField.Value = max([100 ceil(app.NbPostEditField.Value/sqrt(2))]);% The minimum number of sampled models is 10
                            end
                % 6 %%%%%%%%%% Kernel density estimation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            progress.Message = 'Initializing KDE . . .';
                            tic;
                            band_possible = logspace(-5,0,100);
                            bandOK = ones(PRE.CCA.m.dim,1).*1e-0;
                            
                            for i = 1 : PRE.CCA.m.dim
                                % Find the mid-point in the distributions:
                                meanD = mean(PRE.CCA.d.score(:,i));
                                meanM = mean(PRE.CCA.m.score(:,i));
                                for j = 1 : length(band_possible)
                                    % Test different bandwidths for those locations:
                                    % If minimum 10 points in zone of 65% probability around mean -> OK
                                    th = 0:pi/50:2*pi;
                                    circleOK(:,1) = band_possible(j)*3 * cos(th) + meanD;
                                    circleOK(:,2) = band_possible(j)*3 * sin(th) + meanM;
                                    inside = inpolygon(PRE.CCA.d.score(:,i),PRE.CCA.m.score(:,i),circleOK(:,1),circleOK(:,2));
                                    if sum(inside) > 0.05*PRE.Models.N % More than 5% of the data are inside the area of interest !
                                        bandOK(i) = band_possible(j);
                                        break
                                    end
                                end
                            end
                            
                            % Function for computation of bivariate Gaussians:
                            z = @(x,y,X,Y,band) (x-X)^2/band^2 + (y-Y)^2/band^2 - (2*(x-X)*(y-Y)/band*band);
                            pdf_compute = @(x,y,X,Y,band) (1/(2*pi()*band*band)*exp(-z(x,y,X,Y,band)/2));
                            th = 0:pi/50:2*pi;
                            circleX = @(X,Y,r) r*cos(th) + X;
                            circleY = @(X,Y,r) r*sin(th) + Y;
                            
                            % 6.2) Computing the KDE for each dimensions
                            progress.Indeterminate = 'off';
                            progress.Message = 'Running KDE . . .';
                            for i = 1 : PRE.CCA.m.dim
                                Dc = PRE.CCA.d.score(:,i);
                                Hc = PRE.CCA.m.score(:,i);
                                [X_KDE,Y_KDE] = meshgrid(min(Dc)-3*bandOK(i):bandOK(i)/2:max(Dc)+3*bandOK(i),min(Hc)-3*bandOK(i)/2:bandOK(i):max(Hc)+3*bandOK(i));
                                nb_points = length(X_KDE(:));
                                KDE_tmp = zeros(nb_points,1);
                                for j = 1 : nb_points
                                    impacts = inpolygon(Dc,Hc,circleX(X_KDE(j),Y_KDE(j),4*bandOK(i)),circleY(X_KDE(j),Y_KDE(j),4*bandOK(i)));
                                    impacts = find(impacts);
                                    for k = 1 : length(impacts)
                                        KDE_tmp(j) = KDE_tmp(j) + pdf_compute(X_KDE(j),Y_KDE(j),Dc(impacts(k)),Hc(impacts(k)),bandOK(i));
                                    end
                                    if mod(j,1000)==0
                                        progress.Value = (j/nb_points)*(1/app.PREBEL.CCA.m.dim)+(i-1)*(1/app.PREBEL.CCA.m.dim);
                                    end
                                end
                                KDE_tmp = reshape(KDE_tmp,size(X_KDE,1),size(X_KDE,2));
                                proba = sum(sum(KDE_tmp.*bandOK(i)^2));
                                KDE_tmp = KDE_tmp./proba;
                                KDE(i) = struct('X',X_KDE,'Y',Y_KDE,'KDE',KDE_tmp,'band',bandOK(i),'axis',Y_KDE(:,1));
                            end
                            PRE.KDE = KDE;
                            app.ITER(iter+1).timing.KDE = toc;
                            
%%%%%% end Addition on 16th of March 2020
                        end
                        tic;
                        nbSamp = app.NbPostEditField.Value;
                        POST = RunBEL1D(app,PRE,nbSamp,progress);
                        if islogical(POST) % POST == false -> outside the distribution!
                            app.ITER = app.ITER(1:iter);% Conserve only the iterations that worked!
                            app.IterationsEditField.Value = iter;
                            break
                        end
                        app.ITER(iter+1).timing.Sampling = toc;
                        iter = iter + 1;
                        POST.Models.Forward = [];
                        app.ITER(iter).PREBEL = PRE;
                        app.ITER(iter).POSTBEL = POST;
                    end
                    app.POSTBEL = app.ITER(end).POSTBEL; % Assigning last iteration to POSTBEL
% end %%%%%%%%%% Finalizing and saving %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    progress.Message = 'Finalizing';
                    progress.Indeterminate = 'on';
                    saved = uiconfirm(app.BEL1DUIFigure,'Save BEL1D results?','Save results','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                    if strcmp(saved,'Yes')
                        progress.Message = 'Saving POSTBEL file';
                        [file, path] = uiputfile({'*.postbel','PREBEL file (*.postbel)'},'Saving POSTBEL','postbel_file.postbel');
                        postbel = app.POSTBEL;
                        save([path file],'postbel');
                        uialert(app.BEL1DUIFigure,'File saved successfully!','Success','Icon','success');
                        if app.IterateCheckBox.Value
                            if isempty(app.ITER(end).POSTBEL.Models.Forward)
                                % Compute the forward model
                                progress.Message = 'Running forward modelling . . .';
                                app.ITER(end).POSTBEL.Models = app.PREBEL.forward.fun(app,app.ITER(end).POSTBEL.Models,progress);
                            end
                            iter = app.ITER;
                            
                            save([path 'iter' file],'iter','-v7.3');
                            uialert(app.BEL1DUIFigure,'Iteration file saved successfully!','Success','Icon','success');
                        end
                    end
                    close(progress);
                end                    
            end
        end

        % Button pushed function: ForwardPostButton
        function ForwardPostButtonPushed(app, event)
            if isempty(app.PREBEL) || isempty(app.POSTBEL.Models)
                uialert(app.BEL1DUIFigure,'You first need to load a PREBEL and data. You also need to run BEL1D!','Warning','Icon','warning');
            else
                progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Running the forward model','Indeterminate','on');
                if app.PREBEL.dataType ~= 3
                    progress.Value = 0;
                    progress.Indeterminate = 'off';
                    app.POSTBEL.Models = app.PREBEL.forward.fun(app,app.POSTBEL.Models,progress);
                else
                    app.POSTBEL.Models = app.PREBEL.forward.fun(app.POSTBEL.Models);
                end
                close(progress);
            end
        end

        % Button pushed function: SavePOSTBELButton
        function SavePOSTBELButtonPushed(app, event)
            if isempty(app.POSTBEL.Models)
                uialert(app.BEL1DUIFigure,'You need to run BEL1D first !','Warning','Icon','warning');
            else
                progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please wait','Message','Saving POSTBEL file','Indeterminate','on');
                [file, path] = uiputfile({'*.postbel','PREBEL file (*.postbel)'},'Saving POSTBEL','postbel_file.postbel');
                postbel = app.POSTBEL;
                save([path file],'postbel');
                uialert(app.BEL1DUIFigure,'File saved successfully!','Success','Icon','success');
                
                if app.IterateCheckBox.Value
                    if isempty(app.ITER(end).POSTBEL.Models.Forward)
                        % Compute the forward model
                        progress.Message = 'Running forward modelling . . .';
                        app.ITER(end).POSTBEL.Models = app.PREBEL.forward.fun(app,app.ITER(end).POSTBEL.Models,progress);
                    end
                    iter = app.ITER;
                    save([path 'iter' file],'iter','-v7.3');
                    uialert(app.BEL1DUIFigure,'Iteration file saved successfully!','Success','Icon','success');
                end
                close(progress);
            end
        end

        % Button pushed function: PostProcessButton
        function PostProcessButtonPushed(app, event)
            if isempty(app.PREBEL) && isempty(app.POSTBEL.Models)
                BEL1DpostPro();
            elseif ~isempty(app.POSTBEL.Models) && ~isempty(app.PREBEL)
                BEL1DpostPro(app.POSTBEL,app.PREBEL);
            elseif ~isempty(app.POSTBEL.Models) && isempty(app.PREBEL)
                BEL1DpostPro(app.POSTBEL);
            else 
                BEL1DpostPro();
            end
        end

        % Cell edit callback: ParametersSelectTable
        function ParametersSelectTableCellEdit(app, event)
            if event.Indices(1) == app.STATE.nbLayers &&  event.Indices(2) == 1
                % Cannot enable the thickness of the last layer
                app.ParametersSelectTable.Data(event.Indices(1),event.Indices(2)) = false;
            end
        end

        % Button pushed function: SelectallParamButton
        function SelectallParamButtonPushed(app, event)
            app.ParametersSelectTable.Data = true(app.STATE.nbLayers,length(app.STATE.paramNames));
            app.ParametersSelectTable.Data(end,1) = false;% The thickness parameter does not exist for the last layer (half-space)
            app.ParametersSelectTable.ColumnEditable = true;
        end

        % Button pushed function: UnselectallParamButton
        function UnselectallParamButtonPushed(app, event)
            app.ParametersSelectTable.Data = false(app.STATE.nbLayers,length(app.STATE.paramNames));
            app.ParametersSelectTable.ColumnEditable = true;
        end

        % Button pushed function: SaveParamButton
        function SaveParamButtonPushed(app, event)
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Saving file','Indeterminate','on');
            [file, path] = uiputfile('*.param','Select file to save');
            [fid, error] = fopen([path file],'w');
            if isempty(error)
                d = app.ParametersSelectTable.Data;
                [nbLayer, nbParam] = size(d);
                Headers = app.STATE.paramNames;
                for i = 1 : nbParam
                    fprintf(fid,'%s\t',Headers{i});
                end
                fprintf(fid,'\n');
                for i = 1 : nbLayer
                    for j = 1 : nbParam
                        if j == 1
                            fprintf(fid,'%d\t',d(i,j));
                        else
                            fprintf(fid,'%f\t',d(i,j));
                        end
                    end
                    fprintf(fid,'\n');
                end
                fclose(fid);
            else
                uialert(app.BEL1DUIFigure,'Unable to create/overwrite file!','Error','Icon','error');
                return
            end
            delete(progress);
        end

        % Button pushed function: LoadParamButton
        function LoadParamButtonPushed(app, event)
            progress = uiprogressdlg(app.BEL1DUIFigure,'Title','Please Wait','Message','Loading from file','Indeterminate','on');
            [file, path] = uigetfile('*.param','Select the parameters file');
            data = importdata([path file],'\t',1);
            Headers = data.colheaders;
            if ~isequal([Headers{:}],[app.STATE.paramNames{:}])
                uialert(app.BEL1DUIFigure,'The file does not correspond to the data type!','Error','Icon','error');
                return
            end
            data = logical(data.data);
            app.ParametersSelectTable.Data = data;
            delete(progress);
        end

        % Button pushed function: OpenexternallyButton
        function OpenexternallyButtonPushed(app, event)
            % This function opens the CCA space figure in an external tab, enabling modifications and exporting.
            % This part also enables the "sensitivity" analysis through CCA space exploration
            BEL1D_CCAexplorer(app.PREBEL);
        end

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxValueChanged(app, event)
            if app.STATE.datatype == 1
                value = app.NoiseCheckBox.Value;
                if value
                    app.ValuenVEditField.Visible = 'on';
                    app.ValuenVEditFieldLabel.Visible = 'on';
                else
                    app.ValuenVEditField.Visible = 'off';
                    app.ValuenVEditFieldLabel.Visible = 'off';
                end
            end
        end

        % Value changed function: IterateCheckBox
        function IterateCheckBoxValueChanged(app, event)
            value = app.IterateCheckBox.Value;
            if value
                app.IterationsEditField.Visible = 'on';
                app.IterationsEditFieldLabel.Visible = 'on';
            else
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEL1DUIFigure = uifigure;
            app.BEL1DUIFigure.Name = 'BEL1D';
            app.BEL1DUIFigure.CloseRequestFcn = createCallbackFcn(app, @Close_GUI, true);
            app.Title = uilabel(app.BEL1DUIFigure);
            app.Title.Text = 'Bayesian Evidential Learning 1D imaging (BEL1D)';
            app.UI_Logos = uiaxes(app.BEL1DUIFigure);
            app.UI_Logos.XTick = [];
            app.UI_Logos.XTickLabel = {'[ ]'};
            app.UI_Logos.YTick = [];
            app.UI_Logos.Visible = 'off';
            app.TabGroup = uitabgroup(app.BEL1DUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @Change_Menu, true);
            app.IntroductionTab = uitab(app.TabGroup);
            app.IntroductionTab.Title = 'Introduction';
            app.Label = uilabel(app.IntroductionTab);
            app.Label.Text = '';
            app.CopyrightInformation = uitextarea(app.IntroductionTab);
            app.CopyrightInformation.Editable = 'off';
            app.CopyrightInformation.Value = {'Copyright 2020 MICHEL Hadrien (University of Liège (Liège, Belgium),'; 'Ghent University (Ghent, Belgium), F.R.S.-FNRS (Brussels, Belgium)'; ''; 'This product is realeased under the BSD-2-Clause license. '; 'For the full copyright informations, see the ''license.md'' file.'};
            app.AuthorInformation = uitextarea(app.IntroductionTab);
            app.AuthorInformation.Editable = 'off';
            app.AuthorInformation.Value = {'Corresponding author: Hadrien MICHEL'; 'Université de Liège, B52'; 'Allée de la découverte, 9'; 'B-4000, Liège'; 'hadrien.michel@uliege.be'};
            app.TextArea = uitextarea(app.IntroductionTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Version 3.0'; ''; '(April 2020)'};
            app.DataType_ButtonGroup = uibuttongroup(app.IntroductionTab);
            app.DataType_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Intro_DataTypeChange, true);
            app.DataType_ButtonGroup.Title = 'Chose your type of data:';
            app.SNMRButton = uitogglebutton(app.DataType_ButtonGroup);
            app.SNMRButton.Text = 'SNMR';
            app.SNMRButton.Value = true;
            app.SurfaceWavesButton = uitogglebutton(app.DataType_ButtonGroup);
            app.SurfaceWavesButton.Text = 'Surface Waves';
            app.OthersButton = uitogglebutton(app.DataType_ButtonGroup);
            app.OthersButton.Text = 'Others';
            app.PriorType_ButtonGroup = uibuttongroup(app.IntroductionTab);
            app.PriorType_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Intro_PriorTypeChange, true);
            app.PriorType_ButtonGroup.Title = 'Type of prior model space:';
            app.UniformButton = uitogglebutton(app.PriorType_ButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.GaussianButton = uitogglebutton(app.PriorType_ButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.LoadmodelsButton = uitogglebutton(app.PriorType_ButtonGroup);
            app.LoadmodelsButton.Text = 'Load models';
            app.PreparationofBEL1DTab = uitab(app.TabGroup);
            app.PreparationofBEL1DTab.Title = 'Preparation of BEL1D';
            app.NumberofpriorrealizationsEditFieldLabel = uilabel(app.PreparationofBEL1DTab);
            app.NumberofpriorrealizationsEditFieldLabel.Text = 'Number of prior realizations';
            app.NumberofpriorrealizationsEditField = uieditfield(app.PreparationofBEL1DTab, 'numeric');
            app.NumberofpriorrealizationsEditField.Limits = [100 Inf];
            app.NumberofpriorrealizationsEditField.RoundFractionalValues = 'on';
            app.NumberofpriorrealizationsEditField.Value = 1000;
            app.ModelsAndConditions = uitabgroup(app.PreparationofBEL1DTab);
            app.ModelSpaceTab = uitab(app.ModelsAndConditions);
            app.ModelSpaceTab.Title = 'Model Space';
            app.PriorModelTable = uitable(app.ModelSpaceTab);
            app.PriorModelTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PriorModelTable.RowName = {};
            app.PriorModelTable.ColumnEditable = [true false false false];
            app.PriorModelTable.CellEditCallback = createCallbackFcn(app, @PriorModelTableCellEdit, true);
            app.PriorSaveButton = uibutton(app.ModelSpaceTab, 'push');
            app.PriorSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PriorSaveButtonPushed, true);
            app.PriorSaveButton.Text = 'Save';
            app.PriorLoadButton = uibutton(app.ModelSpaceTab, 'push');
            app.PriorLoadButton.ButtonPushedFcn = createCallbackFcn(app, @PriorLoadButtonPushed, true);
            app.PriorLoadButton.Text = 'Load';
            app.PriorInitializeButton = uibutton(app.ModelSpaceTab, 'push');
            app.PriorInitializeButton.ButtonPushedFcn = createCallbackFcn(app, @PriorInitializeButtonPushed, true);
            app.PriorInitializeButton.Text = 'Initialize';
            app.ConditionsTab = uitab(app.ModelsAndConditions);
            app.ConditionsTab.Title = 'Conditions';
            app.ConditionsTable = uitable(app.ConditionsTab);
            app.ConditionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ConditionsTable.RowName = {};
            app.ConditionsTable.ColumnEditable = [true false false false];
            app.ConditionsTable.CellEditCallback = createCallbackFcn(app, @ConditionsTableCellEdit, true);
            app.NoConditionsLabel = uilabel(app.ConditionsTab);
            app.NoConditionsLabel.Text = 'Enable conditions by selecting the "Adding conditions?" button.';
            app.ConditionsSaveButton = uibutton(app.ConditionsTab, 'push');
            app.ConditionsSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConditionsSaveButtonPushed, true);
            app.ConditionsSaveButton.Text = 'Save';
            app.ConditionsLoadButton = uibutton(app.ConditionsTab, 'push');
            app.ConditionsLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ConditionsLoadButtonPushed, true);
            app.ConditionsLoadButton.Text = 'Load';
            app.ConditionsInitializeButton = uibutton(app.ConditionsTab, 'push');
            app.ConditionsInitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ConditionsInitializeButtonPushed, true);
            app.ConditionsInitializeButton.Text = 'Initialize';
            app.ParametersselectionTab = uitab(app.ModelsAndConditions);
            app.ParametersselectionTab.Title = 'Parameters selection';
            app.ParametersSelectTable = uitable(app.ParametersselectionTab);
            app.ParametersSelectTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ParametersSelectTable.RowName = {};
            app.ParametersSelectTable.CellEditCallback = createCallbackFcn(app, @ParametersSelectTableCellEdit, true);
            app.SelectallParamButton = uibutton(app.ParametersselectionTab, 'push');
            app.SelectallParamButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallParamButtonPushed, true);
            app.SelectallParamButton.Text = 'Select all';
            app.UnselectallParamButton = uibutton(app.ParametersselectionTab, 'push');
            app.UnselectallParamButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectallParamButtonPushed, true);
            app.UnselectallParamButton.Text = 'Unselect all';
            app.LoadParamButton = uibutton(app.ParametersselectionTab, 'push');
            app.LoadParamButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamButtonPushed, true);
            app.LoadParamButton.Text = 'Load';
            app.SaveParamButton = uibutton(app.ParametersselectionTab, 'push');
            app.SaveParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamButtonPushed, true);
            app.SaveParamButton.Text = 'Save';
            app.AddingConditionsButton = uibutton(app.PreparationofBEL1DTab, 'state');
            app.AddingConditionsButton.ValueChangedFcn = createCallbackFcn(app, @AddingConditions_PREBEL, true);
            app.AddingConditionsButton.Text = 'Adding conditions?';
            app.RunBEL1DPreparationButton = uibutton(app.PreparationofBEL1DTab, 'push');
            app.RunBEL1DPreparationButton.ButtonPushedFcn = createCallbackFcn(app, @RunBEL1DPreparationButtonPushed, true);
            app.RunBEL1DPreparationButton.Text = 'Run BEL1D Preparation';
            app.LoadingparametersButton = uibutton(app.PreparationofBEL1DTab, 'push');
            app.LoadingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingparametersButtonPushed, true);
            app.LoadingparametersButton.Text = 'Loading parameters';
            app.NoDescriptionText = uilabel(app.PreparationofBEL1DTab);
            app.NoDescriptionText.Text = 'Models loaded from ''*.postbel'' file. No further description available.';
            app.PostacquisitionBEL1DTab = uitab(app.TabGroup);
            app.PostacquisitionBEL1DTab.Title = 'Post-acquisition BEL1D';
            app.KDEAxis = uiaxes(app.PostacquisitionBEL1DTab);
            title(app.KDEAxis, 'CCA dimensions')
            app.CCAdimensiontodisplaySpinnerLabel = uilabel(app.PostacquisitionBEL1DTab);
            app.CCAdimensiontodisplaySpinnerLabel.Text = 'CCA dimension to display';
            app.CCAdimSpinner = uispinner(app.PostacquisitionBEL1DTab);
            app.CCAdimSpinner.ValueChangedFcn = createCallbackFcn(app, @CCAdimSpinnerValueChanged, true);
            app.LoadFieldDataButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.LoadFieldDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFieldDataButtonPushed, true);
            app.LoadFieldDataButton.Text = 'Load Field Data';
            app.NumberofposteriormodelsEditFieldLabel = uilabel(app.PostacquisitionBEL1DTab);
            app.NumberofposteriormodelsEditFieldLabel.Text = 'Number of posterior models';
            app.NbPostEditField = uieditfield(app.PostacquisitionBEL1DTab, 'numeric');
            app.NbPostEditField.Limits = [10 Inf];
            app.NbPostEditField.RoundFractionalValues = 'on';
            app.NbPostEditField.ValueDisplayFormat = '%.0f';
            app.NbPostEditField.Value = 1000;
            app.LoadPREBELButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.LoadPREBELButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPREBELButtonPushed, true);
            app.LoadPREBELButton.Text = 'Load data from preparation of BEL1D';
            app.RunBEL1DButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.RunBEL1DButton.ButtonPushedFcn = createCallbackFcn(app, @RunBEL1DButtonPushed, true);
            app.RunBEL1DButton.Text = 'Run BEL1D finalization';
            app.SavePOSTBELButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.SavePOSTBELButton.ButtonPushedFcn = createCallbackFcn(app, @SavePOSTBELButtonPushed, true);
            app.SavePOSTBELButton.Text = 'Save the results';
            app.PostProcessButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.PostProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PostProcessButtonPushed, true);
            app.PostProcessButton.Text = 'Post-Processing toolbox';
            app.ForwardPostButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.ForwardPostButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardPostButtonPushed, true);
            app.ForwardPostButton.Text = 'Forward modelling of the posterior models';
            app.OpenexternallyButton = uibutton(app.PostacquisitionBEL1DTab, 'push');
            app.OpenexternallyButton.ButtonPushedFcn = createCallbackFcn(app, @OpenexternallyButtonPushed, true);
            app.OpenexternallyButton.Text = 'Open externally';
            app.NoiseCheckBox = uicheckbox(app.PostacquisitionBEL1DTab);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.ValuenVEditFieldLabel = uilabel(app.PostacquisitionBEL1DTab);
            app.ValuenVEditFieldLabel.Text = 'Value (nV):';
            app.ValuenVEditField = uieditfield(app.PostacquisitionBEL1DTab, 'numeric');
            app.ValuenVEditField.Limits = [0 Inf];
            app.IterateCheckBox = uicheckbox(app.PostacquisitionBEL1DTab);
            app.IterateCheckBox.ValueChangedFcn = createCallbackFcn(app, @IterateCheckBoxValueChanged, true);
            app.IterateCheckBox.Text = 'Iterate?';
            app.IterationsEditFieldLabel = uilabel(app.PostacquisitionBEL1DTab);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.PostacquisitionBEL1DTab, 'numeric');
            app.IterationsEditField.Limits = [1 20];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEL1D

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEL1DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start_GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEL1DUIFigure)
        end
    end
end