classdef BEL1D_CCAexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CCAexplorerUIFigure    matlab.ui.Figure
        CCAspaceexplorerLabel  matlab.ui.control.Label
        KDEGraph               matlab.ui.control.UIAxes
        LocalSens              matlab.ui.control.UIAxes
        GlobalSens             matlab.ui.control.UIAxes
        CCAdimensiontodisplaySpinnerLabel  matlab.ui.control.Label
        CCAdimSpinner          matlab.ui.control.Spinner
    end


    properties (Access = public)
        CloseGUI % Description
    end

    properties (Access = private)
        correlation % Description
        sensitivity
        loadsCCA
        PREBEL
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PREBELin)
            app.CloseGUI = false;
            app.PREBEL = PREBELin;
            dim = app.PREBEL.CCA.m.dim;
            if dim > 1
                app.CCAdimSpinner.Visible = 'on';
                app.CCAdimensiontodisplaySpinnerLabel.Visible = 'on';
                app.CCAdimSpinner.Limits = [1, dim];
            else
                app.CCAdimSpinner.Value = 1;
                app.CCAdimSpinner.Visible = 'off';
                app.CCAdimensiontodisplaySpinnerLabel.Visible = 'off';
            end
            %% Compute the sensitivity (through CCA analysis):
            % 1) Gather the correlation of each dimension:
            CCAm = app.PREBEL.CCA.m.score;
            CCAd = app.PREBEL.CCA.d.score;
            app.correlation = zeros(1,dim);
            for i = 1 : dim
                corr = corrcoef(CCAm(:,i),CCAd(:,i));
                app.correlation(i) = corr(1,2);
            end
            % 2) Gather the loads of the parameters in each dimension
            LoadsCCAm = app.PREBEL.CCA.m.coeff;
            % Normalizing the loads to 1 for each column (loads for a given CCA dim) and absolute value
            LoadsCCAm = abs(LoadsCCAm)./repmat(sum(abs(LoadsCCAm),2),1,dim);
            app.loadsCCA = LoadsCCAm;
            % 3) Compute sensitivty sum(load*corr)
            app.sensitivity = LoadsCCAm.*repmat(app.correlation,dim,1);
            % 4) Show the global sensitivity
            bar(app.GlobalSens,sum(app.sensitivity,2));
%             xticks(app.GlobalSens,app.PREBEL.Models.param.names);
            %% Show the local sensitivity for dim = 1
            bar(app.LocalSens,app.loadsCCA(:,1));            
%             xticks(app.LocalSens,app.PREBEL.Models.param.names);
            %% Show the KDE for dim = 1
            hold(app.KDEGraph,'off');
            imagesc(app.KDEGraph,app.PREBEL.KDE(1).X(1,:),app.PREBEL.KDE(1).Y(:,1)',app.PREBEL.KDE(1).KDE);
            axis(app.KDEGraph,'tight');
        end

        % Close request function: CCAexplorerUIFigure
        function CCAexplorerUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: CCAdimSpinner
        function CCAdimSpinnerValueChanged(app, event)
            value = app.CCAdimSpinner.Value;
            % Show the KDE graph for the corresponding dimension
            hold(app.KDEGraph,'off');
            imagesc(app.KDEGraph,app.PREBEL.KDE(value).X(1,:),app.PREBEL.KDE(value).Y(:,1)',app.PREBEL.KDE(value).KDE);
            axis(app.KDEGraph,'tight');
            % Show the sensitivity graph for the corresponding dimension
            bar(app.LocalSens,app.loadsCCA(:,value));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CCAexplorerUIFigure = uifigure;
            app.CCAexplorerUIFigure.Name = 'CCA explorer';
            app.CCAexplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @CCAexplorerUIFigureCloseRequest, true);
            app.CCAspaceexplorerLabel = uilabel(app.CCAexplorerUIFigure);
            app.CCAspaceexplorerLabel.Text = 'CCA space explorer';
            app.KDEGraph = uiaxes(app.CCAexplorerUIFigure);
            title(app.KDEGraph, 'CCA dimension')
            xlabel(app.KDEGraph, 'd_c')
            ylabel(app.KDEGraph, 'm_c')
            app.LocalSens = uiaxes(app.CCAexplorerUIFigure);
            title(app.LocalSens, 'CCA dimension loads')
            xlabel(app.LocalSens, 'Parameters')
            ylabel(app.LocalSens, 'Normlalized loads [/]')
            app.GlobalSens = uiaxes(app.CCAexplorerUIFigure);
            title(app.GlobalSens, 'Global sensitivity')
            xlabel(app.GlobalSens, 'Parameters')
            ylabel(app.GlobalSens, 'Sensitivity estimates [/]')
            app.CCAdimensiontodisplaySpinnerLabel = uilabel(app.CCAexplorerUIFigure);
            app.CCAdimensiontodisplaySpinnerLabel.Text = 'CCA dimension to display';
            app.CCAdimSpinner = uispinner(app.CCAexplorerUIFigure);
            app.CCAdimSpinner.Limits = [1 5];
            app.CCAdimSpinner.ValueChangedFcn = createCallbackFcn(app, @CCAdimSpinnerValueChanged, true);
            app.CCAdimSpinner.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEL1D_CCAexplorer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CCAexplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CCAexplorerUIFigure)
        end
    end
end