classdef BEL1DpostPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PostProUIFigure            matlab.ui.Figure
        BEL1DPostprocessingtoolboxLabel  matlab.ui.control.Label
        LoadPOSTBELButton          matlab.ui.control.Button
        LoadPREBELButton           matlab.ui.control.Button
        DisplayModelsButton        matlab.ui.control.Button
        DisplayParamButton         matlab.ui.control.Button
        UIAxesLogos                matlab.ui.control.UIAxes
        DataSpaceButton            matlab.ui.control.Button
        StatPanel                  matlab.ui.container.Panel
        StatTable                  matlab.ui.control.Table
        ExportStatButton           matlab.ui.control.Button
        POSTBELfileLabel           matlab.ui.control.Label
        POSTBELfileSpinner         matlab.ui.control.Spinner
        PropertyNameDropDownLabel  matlab.ui.control.Label
        PropertyDropDown           matlab.ui.control.DropDown
    end


    properties (Access = private)
        PREBEL % Description
        POSTBEL % Description
        STATE % Description
    end

    methods (Access = private)
    
        function stat = computeStat(~,postbel)
            nbParam = length(postbel.Models.param.namesGlobal);
            nbLayers = postbel.Models.nbLayers;
            % Grouping the parameters accordingly:
            % Thickness first!
            for i = 1 : nbParam
                P(i).name = postbel.Models.param.namesGlobal{i};
                if i == 1
                    P(i).values = postbel.Models.models.All(:,1:nbLayers-1);
                    P(i).mean = mean(P(i).values,1);% Mean values
                    P(i).std = std(P(i).values,1,1);% Standard deviations
                    P(i).min = min(P(i).values,[],1);
                    P(i).max = max(P(i).values,[],1);
                    for j = 1 : nbLayers-1
                        P(i).isGauss(j) = ~kstest(((P(i).values(:,j)-P(i).mean(j))/P(i).std(j)));
                    end
                else
                    P(i).values = postbel.Models.models.All(:,(i-1)*nbLayers:i*nbLayers-1);
                    P(i).mean = mean(P(i).values,1);% Mean values
                    P(i).std = std(P(i).values,1,1);% Standard deviations
                    P(i).min = min(P(i).values,[],1);
                    P(i).max = max(P(i).values,[],1);
                    for j = 1 : nbLayers
                        P(i).isGauss(j) = ~kstest(((P(i).values(:,j)-P(i).mean(j))/P(i).std(j)));
                    end
                end
            end
            for i = 1 : nbParam
                if i == 1
                    stat.table(i).values = cell(nbLayers-1,5);
                    tmp = [P(i).min' P(i).mean' P(i).max' P(i).std' P(i).isGauss'];
                    stat.table(i).rowNames = {'Layer 1'};
                    stat.table(i).values{1,1} = tmp(1,1);
                    stat.table(i).values{1,2} = tmp(1,2);
                    stat.table(i).values{1,3} = tmp(1,3);
                    stat.table(i).values{1,4} = tmp(1,4);
                    stat.table(i).values{1,5} = logical(tmp(1,5));
                    for j = 2 : nbLayers-1
                        stat.table(i).values{j,1} = tmp(j,1);
                        stat.table(i).values{j,2} = tmp(j,2);
                        stat.table(i).values{j,3} = tmp(j,3);
                        stat.table(i).values{j,4} = tmp(j,4);
                        stat.table(i).values{j,5} = logical(tmp(j,5));
                        stat.table(i).rowNames{j} = ['Layer ' num2str(j)];
                    end
                else
                    stat.table(i).values = cell(nbLayers,5);
                    tmp = [P(i).min' P(i).mean' P(i).max' P(i).std' P(i).isGauss'];
                    stat.table(i).rowNames = {'Layer 1'};
                    stat.table(i).values{1,1} = tmp(1,1);
                    stat.table(i).values{1,2} = tmp(1,2);
                    stat.table(i).values{1,3} = tmp(1,3);
                    stat.table(i).values{1,4} = tmp(1,4);
                    stat.table(i).values{1,5} = logical(tmp(1,5));
                    for j = 2 : nbLayers
                        stat.table(i).values{j,1} = tmp(j,1);
                        stat.table(i).values{j,2} = tmp(j,2);
                        stat.table(i).values{j,3} = tmp(j,3);
                        stat.table(i).values{j,4} = tmp(j,4);
                        stat.table(i).values{j,5} = logical(tmp(j,5));
                        stat.table(i).rowNames{j} = ['Layer ' num2str(j)];
                    end
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, postbel, prebel)
            logo = imread('Logos_small.png');
            imshow(logo,'parent',app.UIAxesLogos);
            % Not available in Matlab 2018a but in 2018b and further 
%             app.LoadPOSTBELButton.Tooltip = 'Loading *.postbel file with BEL1D results';
%             app.LoadPREBELButton.Tooltip = 'Loading *.prebel file (BEL1D prior)';
%             app.DisplayModelsButton.Tooltip = 'Display the posterior models in a figure';
%             app.DisplayParamButton.Tooltip = 'Display posterior distributions of the parameters';
            set(app.StatPanel,'Visible','off');
            app.DataSpaceButton.Visible = 'off';
            if nargin < 2 % No user input arguments
                app.PREBEL = [];
                app.POSTBEL = [];
                app.STATE.nbPOST = 0;% Number of post files loaded
                app.STATE.PRE = false;% Preble existing of not
                app.STATE.STAT = [];% Container for the stat on parameters
                app.DisplayModelsButton.Visible = 'off';
                app.DisplayParamButton.Visible = 'off';
                app.DataSpaceButton.Visible = 'off';
            elseif nargin < 3 % Only a postbel file
                app.POSTBEL = postbel;
                app.PREBEL = [];
                app.STATE.nbPOST = 1;
                app.STATE.PRE = false;
                app.STATE.STAT(1) = computeStat(app,app.POSTBEL);% Container for the stat on parameters
                app.DisplayModelsButton.Visible = 'on';
                app.DisplayParamButton.Visible = 'on';
                if isfield(app.POSTBEL.Models,'Forward') && ~isempty(app.POSTBEL.Models.Forward)
                    % The forward model was not run for one of the choosen postbel
                    app.DataSpaceButton.Visible = 'on';
                end
                app.PropertyDropDown.Items = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal;
                app.PropertyDropDown.Value = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal{1};% Default = display thickness
                app.StatTable.Data = app.STATE.STAT(app.STATE.nbPOST).table(1).values;
                app.StatTable.RowName = app.STATE.STAT(app.STATE.nbPOST).table(1).rowNames;
                set(app.POSTBELfileSpinner,'Visible','off');
                set(app.StatPanel,'Visible','on');
            else % Postbel and prebel files
                app.POSTBEL = postbel;
                app.PREBEL = prebel;
                app.STATE.nbPOST = 1;
                app.STATE.PRE = true;
                app.DisplayModelsButton.Visible = 'on';
                app.DisplayParamButton.Visible = 'on';
                app.STATE.STAT(1) = computeStat(app,app.POSTBEL);% Container for the stat on parameters
                if isfield(app.POSTBEL.Models,'Forward') && ~isempty(app.POSTBEL.Models.Forward)
                    % The forward model was not run for one of the choosen postbel
                    app.DataSpaceButton.Visible = 'on';
                end
                app.PropertyDropDown.Items = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal;
                app.PropertyDropDown.Value = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal{1};% Default = display thickness
                app.StatTable.Data = app.STATE.STAT(app.STATE.nbPOST).table(1).values;
                app.StatTable.RowName = app.STATE.STAT(app.STATE.nbPOST).table(1).rowNames;
                set(app.POSTBELfileSpinner,'Visible','off');
                set(app.StatPanel,'Visible','on');
            end
            
        end

        % Button pushed function: LoadPOSTBELButton
        function LoadPOSTBELButtonPushed(app, event)
            if app.STATE.nbPOST > 0
                answer = uiconfirm(app.PostProUIFigure,'Do you want to append to other file (iterations)?','Append','Options',{'Yes','No'},'DefaultOption',2,'Icon','question');
            else
                answer = 'No';
            end
            
            switch answer
                case 'Yes'
                    progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading POSTBEL file . . .','Indeterminate','on');
                    if app.STATE.nbPOST == 1
                        tmp = app.POSTBEL;
                        app.POSTBEL(1) = tmp;
                    end
                    [file, path] = uigetfile({'*.postbel','POSTBEL file (*.postbel)'},'Select postbel file');
                    if file == 0
                        uialert(app.PostProUIFigure,'No selection made!','Error','Icon','error');
                        close(progress);
                        return
                    end
                    postbel = load([path file],'-mat');
                    if isfield(postbel,'iter')
                        uialert(app.PostProUIFigure,'Previous files are deleted when loading multiple iterations!','Warning','Icon','warning');
                        nbIter = max(size(postbel.iter));
                        app.STATE.nbPOST = 0;
                        app.POSTBEL = [];
                        for i = 1 : nbIter
                            app.STATE.nbPOST = app.STATE.nbPOST + 1;
                            tmp = postbel.iter(i).POSTBEL;
                            if i == 1
                                app.POSTBEL = tmp;
                                app.STATE.STAT = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                            else
                                app.POSTBEL(app.STATE.nbPOST) = tmp;
                                app.STATE.STAT(app.STATE.nbPOST) = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                            end
%                             progress.Message = 'Computing statistics';
%                             app.STATE.STAT(app.STATE.nbPOST) = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                        end
                    else
                        app.STATE.nbPOST = app.STATE.nbPOST + 1;
                        app.POSTBEL(app.STATE.nbPOST) = postbel.postbel;
                        progress.Message = 'Computing statistics';
                        app.STATE.STAT(app.STATE.nbPOST) = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                    end
                case 'No'
                    progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading POSTBEL file . . .','Indeterminate','on');
                    app.POSTBEL = [];
                    [file, path] = uigetfile({'*.postbel','POSTBEL file (*.postbel)'},'Select postbel file');
                    if file == 0
                        uialert(app.PostProUIFigure,'No selection made!','Error','Icon','error');
                        close(progress);
                        return
                    end
                    postbel = load([path file],'-mat');
                    if isfield(postbel,'iter')
                        nbIter = max(size(postbel.iter));
%                         app.POSTBEL(nbIter) = [];
                        app.STATE.nbPOST = 0;
                        for i = 1 : nbIter
                            app.STATE.nbPOST = app.STATE.nbPOST + 1;
                            tmp = postbel.iter(i).POSTBEL;
                            if i == 1
                                app.POSTBEL = tmp;
                                app.STATE.STAT = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                            else
                                app.POSTBEL(app.STATE.nbPOST) = tmp;
                                app.STATE.STAT(app.STATE.nbPOST) = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                            end
%                             progress.Message = 'Computing statistics';
%                             app.STATE.STAT(app.STATE.nbPOST) = computeStat(app,app.POSTBEL(app.STATE.nbPOST));% Container for the stat on parameters
                        end
                    else
                        app.POSTBEL = postbel.postbel;
                        app.STATE.nbPOST = 1;
                        progress.Message = 'Computing statistics';
                        %tmp = computeStat(app,app.POSTBEL(1));% Container for the stat on parameters
                        app.STATE.STAT = computeStat(app,app.POSTBEL(1));% Container for the stat on parameters
                    end
                    app.DisplayModelsButton.Visible = 'on';
                    app.DisplayParamButton.Visible = 'on';
                otherwise
                    uialert(app.PostProUIFigure,'No selection made!','Error','Icon','error');
                    return
            end
            progress.Message = 'Displaying statistics';
            % Displaying statistics
            if app.STATE.nbPOST == 1
                set(app.POSTBELfileSpinner,'Visible','off');
            else
                app.POSTBELfileSpinner.Limits = [1, app.STATE.nbPOST];
                app.POSTBELfileSpinner.Value = app.STATE.nbPOST;
                set(app.POSTBELfileSpinner,'Visible','on');
            end
            app.PropertyDropDown.Items = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal;
            app.PropertyDropDown.Value = app.POSTBEL(app.STATE.nbPOST).Models.param.namesGlobal{1};% Default = display thickness
            app.StatTable.Data = app.STATE.STAT(app.STATE.nbPOST).table(1).values;
            app.StatTable.RowName = app.STATE.STAT(app.STATE.nbPOST).table(1).rowNames;
            set(app.StatPanel,'Visible','on');
            if app.STATE.nbPOST > 0
                if isfield(app.POSTBEL(1).Models,'Forward') && ~isempty(app.POSTBEL(1).Models.Forward)
                    % The forward model was not run for one of the choosen postbel
                    app.DataSpaceButton.Visible = 'on';
                end
            end
            close(progress);
        end

        % Button pushed function: LoadPREBELButton
        function LoadPREBELButtonPushed(app, event)
            progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading PREBEL file . . .','Indeterminate','on');
            [file, path] = uigetfile({'*.prebel','PREBEL file (*.prebel)'},'Select prebel file');
            if file == 0
                uialert(app.PostProUIFigure,'No selection made!','Error','Icon','error');
                close(progress);
                return
            end
            prebel = load([path file],'-mat');
            app.STATE.PRE = true;
            app.PREBEL = prebel.prebel;
            close(progress);
        end

        % Button pushed function: DisplayModelsButton
        function DisplayModelsButtonPushed(app, event)
            % 1) Asking if all the postbel file are to be displayed
            % 2) Asking if the prior should be displayed under
            progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading configuration . . .','Indeterminate','on');
            tmp = Menu(app.STATE,1);
            waitfor(tmp,'CloseGUI',true);
            Selection = tmp.Config;
            delete(tmp);
            progress.Message = 'Displaying the models . . .';
            map = colormap(app.UIAxesLogos,'lines');
            POSTBELUsed = find(Selection.POSTBELList);
            if Selection.RMS
                for i = 1 : sum(Selection.POSTBELList)
                    if ~isfield(app.POSTBEL(POSTBELUsed(i)).Models,'Forward') || isempty(app.POSTBEL(POSTBELUsed(i)).Models.Forward)
                        % The forward model was not run for one of the choosen postbel
                        Selection.RMS = false;
                        break
                    end
                end
            end
            % Arranging the data prior to display
            ModelsDisp.All = [];
            ModelsDisp.Color = [];
            ModelsDisp.Forward = [];
            for i = 1 : sum(Selection.POSTBELList)
                ModelsDisp.All = [ModelsDisp.All;app.POSTBEL(POSTBELUsed(i)).Models.models.All];
                ModelsDisp.Color = [ModelsDisp.Color; repmat(map(i,:),size(app.POSTBEL(POSTBELUsed(i)).Models.models.All,1),1)];
                if Selection.RMS
                    ModelsDisp.Forward = [ModelsDisp.Forward; app.POSTBEL(POSTBELUsed(i)).Models.Forward];
                end
            end
            if Selection.PREBEL
                ModelsDisp.All = [app.PREBEL.Select.Save; ModelsDisp.All];
                ModelsDisp.Color = [repmat([0.5 0.5 0.5],size(app.PREBEL.Select.Save,1),1); ModelsDisp.Color];
                if Selection.RMS
                    ModelsDisp.Forward = [app.PREBEL.Models.Forward; ModelsDisp.Forward];
                end
            end
            % 3) Computing RMS (if forward exists)
            if Selection.RMS
                ModelsDisp.RMS = rms(ModelsDisp.Forward - repmat(app.POSTBEL(1).Data.data,size(ModelsDisp.Forward,1),1),2);
                ModelsDisp.ColorRMS = ModelsDisp.Color;
                IndexRMS = true(size(ModelsDisp.RMS,1),1);
                if Selection.PREBEL
                    IndexRMS(1:size(app.PREBEL.Select.Save,1)) = false;
                end
                RMS = ModelsDisp.RMS(IndexRMS);
                [RMS,I] = sort(RMS,'descend');
                if Selection.PREBEL
                    I = [(1:size(app.PREBEL.Select.Save,1))'; (I+size(app.PREBEL.Select.Save,1))];
                end
                ModelsDisp.All = ModelsDisp.All(I,:);
                RMS_fact = (RMS)./(max(RMS)+0.005);
                map = colormap(app.UIAxesLogos,'jet');
                nb_map = length(map);
                Q_used = (0 : nb_map-1)./(nb_map-1);
                RMS_Interval = quantile(RMS,Q_used);
                RMS_fact_map = ones(1,length(RMS));
                Q_act = 1;
                for i = length(RMS) : -1 : 2
                    RMS_fact_map(i) = Q_act;
                    if RMS(i-1) >= RMS_Interval(Q_act)
                        Q_act = Q_act + 1;
                    end
                end
                RMS_fact_map(1) = nb_map;
                ModelsDisp.ColorRMS(IndexRMS,:) = map(RMS_fact_map,:);
            end
            % 4) Dislpaying the models in an external figure
            screenSize = get(0,'ScreenSize');
            if screenSize(3) < screenSize(4) % vertical screen
                pos = [ceil(0.1*screenSize(3)) ceil(0.1*screenSize(3)) ceil(0.8*screenSize(3)) ceil(0.8*screenSize(4))];
            else % Horizontal screen
                pos = [ceil(0.1*screenSize(4)) ceil(0.1*screenSize(4)) ceil(0.8*screenSize(3)/2) ceil(0.8*screenSize(4))];
            end
            fig = figure('Name','BEL1D Model Display','Position',pos,'Resize','on');
            nbParam = length(app.POSTBEL(1).Models.param.namesGlobal)-1;
            nbLayers = app.POSTBEL(1).Models.nbLayers;
            % Sorting the data:
            ModelsDisp.Param = cell(nbParam+1,1);
            ModelsDisp.Axis = cell(nbParam,1);
            for i = 1 : nbParam+1
                if i == 1
                    ModelsDisp.Param{i} = ModelsDisp.All(:,1:nbLayers-1);
                else
                    ModelsDisp.Param{i} = ModelsDisp.All(:,(i-1)*nbLayers:i*nbLayers-1);
                    if Selection.RMS % Letting room for the RMS scalebar
                        largeur = 0.9*pos(3);
                        hauteur = 0.7*pos(4);
                        posOut = [0.05*pos(3)+(largeur/nbParam*(i-2)) 0.20*pos(4) largeur/nbParam hauteur];
                        posIn = [0.1*pos(3)+(largeur/nbParam*(i-2)) 0.25*pos(4) largeur/nbParam-0.05*pos(3) hauteur-0.05*pos(4)];
                        ModelsDisp.Axis{i-1} = axes(fig,'Units','pixels','OuterPosition',posOut,'Position',posIn);
                    else % Using the full available space
                        largeur = 0.9*pos(3);
                        hauteur = 0.85*pos(4);
                        posOut = [0.05*pos(3)+(largeur/nbParam*(i-2)) 0.05*pos(4) largeur/nbParam hauteur];%[0.05*pos(3)+(largeur/nbParam*(i-2))+(0.025*pos(3)*(i-1)) 0.05*pos(4) largeur/nbParam-(0.025*pos(3)*(i-1)) hauteur];
                        posIn = [0.1*pos(3)+(largeur/nbParam*(i-2)) 0.1*pos(4) largeur/nbParam-0.05*pos(3) hauteur-0.05*pos(4)];
                        ModelsDisp.Axis{i-1} = axes(fig,'Units','pixels','OuterPosition',posOut,'Position',posIn);
                    end
                end
            end
            %uilabel(fig,'Position',[0.1*pos(3) 0.95*pos(4) 0.8*pos(3) 0.05*pos(4)],'FontSize',25,'VerticalAlignment','bottom','HorizontalAlignment','center','Text','BEL1D : Model display','FontWeight','bold');
            
            dMax = ceil(max(max(cumsum(ModelsDisp.Param{1},2)/10)))*10;
            if size(ModelsDisp.All,1) > 5000
                pas = 5;
            else
                pas = 1;
            end
            if Selection.RMS % Displaying the models with the RMS error
                for i = 1 : nbParam
                    hold(ModelsDisp.Axis{i},'on');
                    for j = 1 : pas : size(ModelsDisp.All,1)
                        stairs(ModelsDisp.Axis{i},[ModelsDisp.Param{i+1}(j,1) ModelsDisp.Param{i+1}(j,:)],[0 cumsum(ModelsDisp.Param{1}(j,:),2) dMax],'Color',ModelsDisp.ColorRMS(j,:))
                    end
                    xlabel(ModelsDisp.Axis{i},app.POSTBEL(1).Models.param.namesGlobal{i+1});
                    if i == 1
                        ylabel(ModelsDisp.Axis{i},'Depth [m]');
                    else
%                         set(ModelsDisp.Axis{i},'YTickLabel','');
                    end
                    set(ModelsDisp.Axis{i},'YDir','reverse');
                    set(ModelsDisp.Axis{i},'FontSize',14);
                    grid(ModelsDisp.Axis{i},'on');
                    hold(ModelsDisp.Axis{i},'off');
                end
                % Adding scalebar
                posOut = [0.05*pos(3) 0.05*pos(4) 0.9*pos(3) 0.1*pos(4)];
                AxisRMS = axes(fig,'Units','pixels','OuterPosition',posOut);
                tmp = min(RMS) : (max(RMS)-min(RMS))/999 : max(RMS);
                for i = 1 : length(tmp)
                    tmp(i) = find(RMS_Interval<=tmp(i),1,'last');
                end
                imagesc(AxisRMS,RMS_Interval,0:1,[(tmp)' (tmp)']');
                colormap(map);
                set(AxisRMS,'YTick',[]);
                xlabel(AxisRMS,'RMS error');
                set(AxisRMS,'FontSize',14);
            else % Only displaying the models with color associated to the iteration
                for i = 1 : nbParam
                    hold(ModelsDisp.Axis{i},'on');
                    for j = 1 : pas : size(ModelsDisp.All,1)
                        stairs(ModelsDisp.Axis{i},[ModelsDisp.Param{i+1}(j,1) ModelsDisp.Param{i+1}(j,:)],[0 cumsum(ModelsDisp.Param{1}(j,:),2) dMax],'Color',ModelsDisp.Color(j,:))
                    end
                    xlabel(ModelsDisp.Axis{i},app.POSTBEL(1).Models.param.namesGlobal{i+1});
                    if i == 1
                        ylabel(ModelsDisp.Axis{i},'Depth [m]');
                    else
%                         set(ModelsDisp.Axis{i},'YTick',[]);
                    end
                    set(ModelsDisp.Axis{i},'YDir','reverse');
                    set(ModelsDisp.Axis{i},'FontSize',16);
                    grid(ModelsDisp.Axis{i},'on');
                    hold(ModelsDisp.Axis{i},'off');
                end
            end
            
            Ax = axes(fig);
            Title = title(Ax,'BEL1D: Model display');
            Ax.Visible = 'off';
            Title.Visible = 'on';
            Title.FontSize = 24;
            close(progress);
            if pas > 1
                uialert(app.PostProUIFigure,'Only 1 model out of 5 represented for practical reasons!','Warning','Icon','Warning');
            end
        end

        % Button pushed function: DisplayParamButton
        function DisplayParamButtonPushed(app, event)
            % 1) Asking if all the postbel files are to be displayed
            % 2) Asking if the prior should be displayed under
            progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading configuration . . .','Indeterminate','on');
            tmp = Menu(app.STATE,2);
            waitfor(tmp,'CloseGUI',true);
            Selection = tmp.Config;
            delete(tmp);
            progress.Message = 'Displaying the models . . .';
            map = colormap(app.UIAxesLogos,'lines');
            POSTBELUsed = find(Selection.POSTBELList);
            % Arranging the data prior to display
            ModelsDisp.All = [];
            ModelsDisp.Color = [];
            ModelsDisp.Forward = [];
            for i = 1 : sum(Selection.POSTBELList)
                ModelsDisp.All = [ModelsDisp.All;app.POSTBEL(POSTBELUsed(i)).Models.models.All];
                ModelsDisp.Color = [ModelsDisp.Color; repmat(map(i,:),size(app.POSTBEL(POSTBELUsed(i)).Models.models.All,1),1)];
            end
            if Selection.PREBEL
                ModelsDisp.All = [app.PREBEL.Select.Save; ModelsDisp.All];
                ModelsDisp.Color = [repmat([0.5 0.5 0.5],size(app.PREBEL.Select.Save,1),1); ModelsDisp.Color];
            end
            % 3) Displaying the parameters in 2 figures
            screenSize = get(0,'ScreenSize');
            if screenSize(3) < screenSize(4) % vertical screen: Two figures one above the other
                pos1 = [ceil(0.1*screenSize(3)) ceil(0.1*screenSize(4)) ceil(0.8*screenSize(3)) ceil(0.4*screenSize(4))];
                pos2 = [ceil(0.1*screenSize(3)) ceil(0.5*screenSize(4)) ceil(0.8*screenSize(3)) ceil(0.4*screenSize(4))];
            else % Horizontal screen: Two figures one beside the other
                pos1 = [ceil(0.1*screenSize(3)) ceil(0.1*screenSize(4)) ceil(0.4*screenSize(3)) ceil(0.6*screenSize(4))];
                pos2 = [ceil(0.5*screenSize(3)) ceil(0.1*screenSize(4)) ceil(0.4*screenSize(3)) ceil(0.6*screenSize(4))];
            end
            fig1 = figure('Name','BEL1D Parameter Display','Position',pos1,'Resize','on');
            fig2 = figure('Name','BEL1D Parameter Display','Position',pos2,'Resize','on');
            %% Displaying parameters and correlations
            nbParam = length(app.POSTBEL(1).Models.param.names);
            set(0,'CurrentFigure',fig1);
            [ColorsUnique, ~, idxOrigin] = unique(ModelsDisp.Color,'stable','rows');
            for i = 1 : nbParam
                for j = 1 : nbParam
                    if j < i
                        subplot(nbParam,nbParam,(i-1)*nbParam+j);
                        hold on;
                        scatter(ModelsDisp.All(:,j),ModelsDisp.All(:,i),5,ModelsDisp.Color,'filled');
                        set(gca,'YTick',[]);
                        set(gca,'XTick',[]);
                        if j == 1
                            ylabel(app.POSTBEL(1).Models.param.names{i},'FontSize',12);
                        end
                        if i == nbParam
                            xlabel(app.POSTBEL(1).Models.param.names{j},'FontSize',12);
                        end
                    elseif i == j
                        subplot(nbParam,nbParam,(i-1)*nbParam+j);
                        hold on;
                        nbModels = size(ColorsUnique,1);
                        for k = 1 : nbModels
                            histogram(ModelsDisp.All(idxOrigin==k,i),'DisplayStyle','stairs','Normalization','pdf','EdgeColor',ColorsUnique(k,:));
                        end
                        set(gca,'YTick',[]);
                        set(gca,'XTick',[]);
                        if j == 1
                            ylabel(app.POSTBEL(1).Models.param.names{j},'FontSize',12);
                        end
                    end
                end
            end
            %% Displaying parameters in layered disposition
            nbParam = length(app.POSTBEL(1).Models.param.namesGlobal);
            nbLayers = app.POSTBEL(1).Models.nbLayers;
            set(0,'CurrentFigure',fig2);
            indexAx = 1 : nbParam*nbLayers;
            indexAx = reshape(indexAx,nbParam,nbLayers)';
            indexAx = indexAx(:);
            k = 1;
            m = 1;
            for i = 1 : nbParam
                for j = 1 : nbLayers
                    currentAx = indexAx(k);
                    subplot(nbLayers,nbParam,currentAx);
                    if j == nbLayers && i == 1
                        Yl = ylabel(['Layer ' num2str(j)],'FontSize',16);
                        set(gca,'Visible','off');
                        Yl.Visible = 'on';
                        k = k + 1;
                    else
                        hold on
                        for l = 1 : nbModels
                            histogram(ModelsDisp.All(idxOrigin==l,m),'DisplayStyle','stairs','Normalization','pdf','EdgeColor',ColorsUnique(l,:),'LineWidth',2);
                        end
                        m = m + 1;
                        k = k + 1;
                        if i == 1
                            ylabel(['Layer ' num2str(j)],'FontSize',16);
                        end
                        if j == 1
                            title(app.POSTBEL(1).Models.param.namesGlobal{i},'FontSize',16);
                        end
                    end
                end
            end
            close(progress);
        end

        % Close request function: PostProUIFigure
        function PostProUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.PostProUIFigure,'Are you sure you want to close?','Confirm Close','Options',{'Yes','Cancel'},...
                'DefaultOption',2,'CancelOption',2,'Icon','warning');
            if strcmp(selection,'Yes')
                delete(app);
            end
        end

        % Button pushed function: DataSpaceButton
        function DataSpaceButtonPushed(app, event)
            progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Loading configuration . . .','Indeterminate','on');
            tmp = Menu(app.STATE,3);
            waitfor(tmp,'CloseGUI',true);
            Selection = tmp.Config;
            delete(tmp);
            progress.Message = 'Displaying the models . . .';
            map = colormap(app.UIAxesLogos,'lines');
            POSTBELUsed = find(Selection.POSTBELList);
            % Arranging the data prior to display
            ModelsDisp.All = [];
            ModelsDisp.Color = [];
            ModelsDisp.Forward = [];
            for i = 1 : sum(Selection.POSTBELList)
                ModelsDisp.All = [ModelsDisp.All;app.POSTBEL(POSTBELUsed(i)).Models.Forward];
                ModelsDisp.Color = [ModelsDisp.Color; repmat(map(i,:),size(app.POSTBEL(POSTBELUsed(i)).Models.models.All,1),1)];
            end
            if Selection.PREBEL
                ModelsDisp.All = [app.PREBEL.Models.Forward; ModelsDisp.All];
                ModelsDisp.Color = [repmat([0.5 0.5 0.5],size(app.PREBEL.Select.Save,1),1); ModelsDisp.Color];
                IndexPost = true(size(ModelsDisp.All,1),1);
                IndexPost(1:size(app.PREBEL.Models.Forward,1)) = false;
            else
                IndexPost = true(size(ModelsDisp.All,1),1);
            end
            % 3) Computing RMS
            if Selection.RMS
                ModelsDisp.RMS = rms(ModelsDisp.All - repmat(app.POSTBEL(1).Data.data,size(ModelsDisp.All,1),1),2);
                ModelsDisp.ColorRMS = ModelsDisp.Color;
                RMS = ModelsDisp.RMS(IndexPost);
                [RMS,I] = sort(RMS,'descend');
                if Selection.PREBEL
                    I = [(1:size(app.PREBEL.Select.Save,1))'; (I+size(app.PREBEL.Select.Save,1))];
                end
                ModelsDisp.All = ModelsDisp.All(I,:);
                RMS_fact = (RMS)./(max(RMS)+0.005);
                map = colormap(app.UIAxesLogos,'jet');
                nb_map = length(map);
                Q_used = (0 : nb_map-1)./(nb_map-1);
                RMS_Interval = quantile(RMS,Q_used);
                RMS_fact_map = ones(1,length(RMS));
                Q_act = 1;
                for i = length(RMS) : -1 : 2
                    RMS_fact_map(i) = Q_act;
                    if RMS(i-1) >= RMS_Interval(Q_act)
                        Q_act = Q_act + 1;
                    end
                end
                RMS_fact_map(1) = nb_map;
                ModelsDisp.ColorRMS(IndexPost,:) = map(RMS_fact_map,:);
                ModelsDisp.Color = ModelsDisp.ColorRMS;
            end
            % 4) Dislpaying the models in an external figure
            fig = figure('Name','Data space evoltution');
            hold on;
            for i = 1 : size(ModelsDisp.All,1)
                plot(ModelsDisp.All(i,:),'Color',ModelsDisp.Color(i,:));
            end
            plot(app.POSTBEL(1).Data.data,'Color','w','Linewidth',2);
            set(gca,'XTick',[]);
        end

        % Button pushed function: ExportStatButton
        function ExportStatButtonPushed(app, event)
            progress = uiprogressdlg(app.PostProUIFigure,'Title','Please wait','Message','Writing statistical report . . .','Indeterminate','on');
            % Function that exports the stats of all the postbel files loaded:
            [file,path] = uiputfile({'*.poststat','Statistics on POSTBEL file (*.poststat)'},'Select the name of the file','poststat.poststat');
            fid = fopen([path file],'w');
            for i = 1 : app.STATE.nbPOST
                fprintf(fid,'%s\n',['POSTBEL file nb.' num2str(i)]);
                for j = 1 : length(app.POSTBEL(i).Models.param.namesGlobal)
                    fprintf(fid,'\t%s\t%s\t%s\t%s\t%s\t%s\n',app.POSTBEL(i).Models.param.namesGlobal{j},'Min','Mean','Max','Std','isGaussian');
                    indxEnd = app.POSTBEL(i).Models.nbLayers;
                    if j == 1
                        indxEnd = indxEnd-1;
                    end
                    for k = 1 : indxEnd
                        fprintf(fid,'\t%s\t%f\t%f\t%f\t%f\t%u\n',['Layer' num2str(k)],app.STATE.STAT(i).table(j).values{k,1},app.STATE.STAT(i).table(j).values{k,2},app.STATE.STAT(i).table(j).values{k,3},app.STATE.STAT(i).table(j).values{k,4},app.STATE.STAT(i).table(j).values{k,5});
                    end
                end
                fprintf(fid,'\n');
            end
            fclose(fid);
            close(progress);
        end

        % Value changed function: POSTBELfileSpinner
        function POSTBELfileSpinnerValueChanged(app, event)
            value = app.PropertyDropDown.Value;
            index = [];
            for i = 1 : length(app.PropertyDropDown.Items)
                if strcmp(value,app.PropertyDropDown.Items{i})
                    index = i;
                    break
                end
            end
            app.StatTable.Data = app.STATE.STAT(app.POSTBELfileSpinner.Value).table(index).values;
            app.StatTable.RowName = app.STATE.STAT(app.POSTBELfileSpinner.Value).table(index).rowNames;
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            value = app.PropertyDropDown.Value;
            index = [];
            for i = 1 : length(app.PropertyDropDown.Items)
                if strcmp(value,app.PropertyDropDown.Items{i})
                    index = i;
                    break
                end
            end
            app.StatTable.Data = app.STATE.STAT(app.POSTBELfileSpinner.Value).table(index).values;
            app.StatTable.RowName = app.STATE.STAT(app.POSTBELfileSpinner.Value).table(index).rowNames;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PostProUIFigure = uifigure;
            app.PostProUIFigure.Name = 'BEL1D Post-Processing toolbox';
            app.PostProUIFigure.CloseRequestFcn = createCallbackFcn(app, @PostProUIFigureCloseRequest, true);
            app.BEL1DPostprocessingtoolboxLabel = uilabel(app.PostProUIFigure);
            app.BEL1DPostprocessingtoolboxLabel.Text = 'BEL1D Post-processing toolbox';
            app.LoadPOSTBELButton = uibutton(app.PostProUIFigure, 'push');
            app.LoadPOSTBELButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPOSTBELButtonPushed, true);
            app.LoadPOSTBELButton.Text = 'Load POSTBEL file';
            app.LoadPREBELButton = uibutton(app.PostProUIFigure, 'push');
            app.LoadPREBELButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPREBELButtonPushed, true);
            app.LoadPREBELButton.Text = 'Load PREBEL file';
            app.DisplayModelsButton = uibutton(app.PostProUIFigure, 'push');
            app.DisplayModelsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayModelsButtonPushed, true);
            app.DisplayModelsButton.Text = 'Display models (with RMS)';
            app.DisplayParamButton = uibutton(app.PostProUIFigure, 'push');
            app.DisplayParamButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayParamButtonPushed, true);
            app.DisplayParamButton.Text = 'Display parameters';
            app.UIAxesLogos = uiaxes(app.PostProUIFigure);
            app.UIAxesLogos.XTick = [];
            app.UIAxesLogos.XTickLabel = {'[]'};
            app.UIAxesLogos.YTick = [];
            app.UIAxesLogos.Visible = 'off';
            app.DataSpaceButton = uibutton(app.PostProUIFigure, 'push');
            app.DataSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @DataSpaceButtonPushed, true);
            app.DataSpaceButton.Text = 'Display data space evolution';
            app.StatPanel = uipanel(app.PostProUIFigure);
            app.StatTable = uitable(app.StatPanel);
            app.StatTable.ColumnName = {'Minimum'; 'Mean'; 'Maximum'; 'Standard deviation'; 'Gaussian ?'};
            app.StatTable.RowName = {};
            app.ExportStatButton = uibutton(app.StatPanel, 'push');
            app.ExportStatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStatButtonPushed, true);
            app.ExportStatButton.Text = 'Export statistics tables';
            app.POSTBELfileLabel = uilabel(app.StatPanel);
            app.POSTBELfileLabel.Text = 'POSTBEL file';
            app.POSTBELfileSpinner = uispinner(app.StatPanel);
            app.POSTBELfileSpinner.Limits = [1 Inf];
            app.POSTBELfileSpinner.ValueDisplayFormat = '%.0f';
            app.POSTBELfileSpinner.ValueChangedFcn = createCallbackFcn(app, @POSTBELfileSpinnerValueChanged, true);
            app.POSTBELfileSpinner.Editable = 'off';
            app.POSTBELfileSpinner.Value = 1;
            app.PropertyNameDropDownLabel = uilabel(app.StatPanel);
            app.PropertyNameDropDownLabel.Text = 'Property Name';
            app.PropertyDropDown = uidropdown(app.StatPanel);
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEL1DpostPro(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PostProUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PostProUIFigure)
        end
    end
end