classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayMenuUI                 matlab.ui.Figure
        DisplaymenuLabel              matlab.ui.control.Label
        ReturntheconfigurationButton  matlab.ui.control.StateButton
        POSTBELfilesmultifileCTRLclickListBoxLabel  matlab.ui.control.Label
        POSTBELfilesmultifileCTRLclickListBox  matlab.ui.control.ListBox
        ShowRMSerrorCheckBox          matlab.ui.control.CheckBox
        DisplayPREBELpriormodelsCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        CloseGUI
        Config% Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STATE, VisitedCase)
            
            app.CloseGUI = false;
            tmp = cell(STATE.nbPOST,1);
            for i = 1 : STATE.nbPOST
                tmp{i} = ['File Nb. ' num2str(i)];
            end
            app.POSTBELfilesmultifileCTRLclickListBox.Items = tmp;
            app.POSTBELfilesmultifileCTRLclickListBox.Value = tmp{1};
            switch VisitedCase
                case 1 % Models with potential RMS
                    % All options available
                case 2 % Parameters 
                    app.ShowRMSerrorCheckBox.Visible = 'off';
                case 3 % Data with potential RMS
                    % All options available
            end
            if ~STATE.PRE
                app.DisplayPREBELpriormodelsCheckBox.Visible = 'off';
            end
            
        end

        % Callback function: DisplayMenuUI, 
        % ReturntheconfigurationButton
        function DisplayMenuUICloseRequest(app, event)
            app.Config.POSTBELList = false(length(app.POSTBELfilesmultifileCTRLclickListBox.Items),1);
            for i = 1 : length(app.POSTBELfilesmultifileCTRLclickListBox.Value)
                for j = 1 : length(app.POSTBELfilesmultifileCTRLclickListBox.Items)
                    if strcmp(app.POSTBELfilesmultifileCTRLclickListBox.Value{i},app.POSTBELfilesmultifileCTRLclickListBox.Items{j})
                        app.Config.POSTBELList(j) = true;
                        break
                    end
                end
            end
            app.Config.RMS = app.ShowRMSerrorCheckBox.Value;
            app.Config.PREBEL = app.DisplayPREBELpriormodelsCheckBox.Value;
            selection = uiconfirm(app.DisplayMenuUI,'Return the configuration? ','Return','Options',{'Yes','Cancel'},...
            'DefaultOption',2,'CancelOption',2,'Icon','success');
            if strcmp(selection,'Yes')
                app.CloseGUI = true;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplayMenuUI = uifigure;
            app.DisplayMenuUI.Name = 'Menu';
            app.DisplayMenuUI.CloseRequestFcn = createCallbackFcn(app, @DisplayMenuUICloseRequest, true);
            app.DisplaymenuLabel = uilabel(app.DisplayMenuUI);
            app.DisplaymenuLabel.Text = 'Display menu';
            app.ReturntheconfigurationButton = uibutton(app.DisplayMenuUI, 'state');
            app.ReturntheconfigurationButton.ValueChangedFcn = createCallbackFcn(app, @DisplayMenuUICloseRequest, true);
            app.ReturntheconfigurationButton.Text = 'Return the configuration';
            app.POSTBELfilesmultifileCTRLclickListBoxLabel = uilabel(app.DisplayMenuUI);
            app.POSTBELfilesmultifileCTRLclickListBoxLabel.Text = 'POSTBEL files (multi-file: CTRL+click)';
            app.POSTBELfilesmultifileCTRLclickListBox = uilistbox(app.DisplayMenuUI);
            app.POSTBELfilesmultifileCTRLclickListBox.Items = {'File Nb. 1'};
            app.POSTBELfilesmultifileCTRLclickListBox.Multiselect = 'on';
            app.POSTBELfilesmultifileCTRLclickListBox.Value = {'File Nb. 1'};
            app.ShowRMSerrorCheckBox = uicheckbox(app.DisplayMenuUI);
            app.ShowRMSerrorCheckBox.Text = ' Show RMS error';
            app.DisplayPREBELpriormodelsCheckBox = uicheckbox(app.DisplayMenuUI);
            app.DisplayPREBELpriormodelsCheckBox.Text = ' Display PREBEL (prior models)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Menu(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayMenuUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayMenuUI)
        end
    end
end