classdef Others_Exp_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OthersExpDesignUIFigure  matlab.ui.Figure
        OthersforwardmodelLabel  matlab.ui.control.Label
        ForwardmodellingfunctionEditFieldLabel  matlab.ui.control.Label
        ForwardFuncEditField     matlab.ui.control.EditField
        ReturnparametersButton   matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
    end

    properties (Access = public)
        Forward % Output of the function
        CloseGUI % Close variable
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CloseGUI = false;
            app.Forward.func = str2func('@(Models) ForwardMod(Models)');
        end

        % Callback function: OthersExpDesignUIFigure, 
        % ReturnparametersButton
        function closeMenu(app, event)
            try
                app.Forward.func = str2func(app.ForwardFuncEditField.Value);
                selection = uiconfirm(app.OthersExpDesignUIFigure,'Return the parameters? ','Return','Options',{'Yes','Cancel'},...
                'DefaultOption',2,'CancelOption',2,'Icon','success');
                if strcmp(selection,'Yes')
                    app.CloseGUI = true;
                end
            catch
                uialert(app.OthersExpDesignUIFigure,'The function you inserted is not a function handle!');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OthersExpDesignUIFigure = uifigure;
            app.OthersExpDesignUIFigure.Name = 'SNMR experimental design';
            app.OthersExpDesignUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeMenu, true);
            app.OthersforwardmodelLabel = uilabel(app.OthersExpDesignUIFigure);
            app.OthersforwardmodelLabel.Text = 'Others forward model';
            app.ForwardmodellingfunctionEditFieldLabel = uilabel(app.OthersExpDesignUIFigure);
            app.ForwardmodellingfunctionEditFieldLabel.Text = 'Forward modelling function:';
            app.ForwardFuncEditField = uieditfield(app.OthersExpDesignUIFigure, 'text');
            app.ForwardFuncEditField.Value = '@(Models) ForwardMod(Models)';
            app.ReturnparametersButton = uibutton(app.OthersExpDesignUIFigure, 'push');
            app.ReturnparametersButton.ButtonPushedFcn = createCallbackFcn(app, @closeMenu, true);
            app.ReturnparametersButton.Text = 'Return parameters';
            app.TextArea = uitextarea(app.OthersExpDesignUIFigure);
            app.TextArea.Value = {'Please ensure that the forward modelling function provided enconters the following conditions:'; '    - It takes as arguments the ''Models'' structure and returns it'; '    - All the experimental parameters are defined inside the function'; '    - The forward model is added to the ''Models.Forward'' structure'; '    - The ''Models.XAxis'' vector contains a recognizable feature of the data'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Others_Exp_Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OthersExpDesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OthersExpDesignUIFigure)
        end
    end
end