classdef SNMR_Exp_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SNMRexperimentaldesignUIFigure  matlab.ui.Figure
        SNMRacquisitionparametersLabel  matlab.ui.control.Label
        KernelFileEditFieldLabel        matlab.ui.control.Label
        KernelFileEditField             matlab.ui.control.EditField
        TdeadsEditFieldLabel            matlab.ui.control.Label
        TdeadsEditField                 matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        TmaxsEditFieldLabel             matlab.ui.control.Label
        TmaxsEditField                  matlab.ui.control.NumericEditField
        LoadKernel                      matlab.ui.control.Button
        LoadparametersfromdatafileButton  matlab.ui.control.Button
        ReturnparametersButton          matlab.ui.control.Button
    end

    properties (Access = public)
        Forward % Output of the function
        CloseGUI % Close variable
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CloseGUI = false;
            app.Forward.t_dead = 0.05;% Default dead time
            app.TdeadsEditField.Value = app.Forward.t_dead;
            app.Forward.frequency = 1000;% Default sampling frequency
            app.FrequencyHzEditField.Value = app.Forward.frequency;
            app.Forward.t_max = 0.5;% Default maximum aquisition time
            app.TmaxsEditField.Value = app.Forward.t_max;
            app.Forward.t_register = app.TdeadsEditField.Value : 1/(app.FrequencyHzEditField.Value) : app.TmaxsEditField.Value;
            app.Forward.kernel = [];
        end

        % Callback function: ReturnparametersButton, 
        % SNMRexperimentaldesignUIFigure
        function closeMenu(app, event)
            app.Forward.t_dead = app.TdeadsEditField.Value;
            app.Forward.frequency = app.FrequencyHzEditField.Value;
            app.Forward.t_max = app.TmaxsEditField.Value;
            app.Forward.t_register = app.TdeadsEditField.Value : 1/(app.FrequencyHzEditField.Value) : app.TmaxsEditField.Value;
            if isempty(app.Forward.kernel)
                uialert(app.SNMRexperimentaldesignUIFigure,'No kernel file was loaded!','Kernel missing');
            else
                selection = uiconfirm(app.SNMRexperimentaldesignUIFigure,'Return the parameters? ','Return','Options',{'Yes','Cancel'},...
                    'DefaultOption',2,'CancelOption',2,'Icon','success');
                if strcmp(selection,'Yes')
                    app.CloseGUI = true;
                end
            end
        end

        % Button pushed function: LoadKernel
        function LoadKernelButtonPushed(app, event)
            [file_kdata, path_kdata] = uigetfile({'*.mrsk','Kernel file (*.mrsk)'},'Select the kernel file');
            kdata = load([path_kdata file_kdata],'-mat');   kdata = kdata.kdata;
            app.Forward.kernel = kdata;
            app.KernelFileEditField.Value = [path_kdata file_kdata];
            if ~strcmp(kdata.measure.pulsesequence,'FID')
                uialert('The kernel file is not a FID pulse sequence!');
            end
        end

        % Value changed function: KernelFileEditField
        function KernelFileEditFieldValueChanged(app, event)
            value = app.KernelFileEditField.Value;
            [~,~,ext] = fileparts(value);
            if ~strcmp(ext,'.mrsk')
                uialert('The extension is not correct. It should be ''$.mrsk''.');
            end
            kdata = load(value,'-mat');   kdata = kdata.kdata;
            if ~strcmp(kdata.measure.pulsesequence,'FID')
                uialert('The kernel file is not a FID pulse sequence!');
            end
            app.Forward.kernel = kdata;
            app.KernelFileEditField.Value = [path_kdata file_kdata];
        end

        % Button pushed function: LoadparametersfromdatafileButton
        function LoadparametersfromdatafileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mrsd','MRSmatlab data file (*.mrsd)'},'Select the data file');
            [~,~,ext] = fileparts([path file]);
            if ~strcmp(ext,'.mrsd')
                uialert('The extension is not correct. It should be ''*.mrsd''.');
            end
            proclog = load([path file],'-mat');
            proclog = proclog.proclog;
            t_register = [proclog.Q(1).rx(1).sig(2).t]+proclog.Q(1).timing.tau_dead1;
            app.Forward.t_dead = t_register(1);
            app.Forward.frequency = 1/(t_register(2)-t_register(1));
            app.Forward.t_max = t_register(end);
            app.Forward.t_register = t_register;
            app.TdeadsEditField.Value = app.Forward.t_dead;
            app.FrequencyHzEditField.Value = app.Forward.frequency;
            app.TmaxsEditField.Value = app.Forward.t_max;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SNMRexperimentaldesignUIFigure = uifigure;
            app.SNMRexperimentaldesignUIFigure.Name = 'SNMR experimental design';
            app.SNMRexperimentaldesignUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeMenu, true);
            app.SNMRacquisitionparametersLabel = uilabel(app.SNMRexperimentaldesignUIFigure);
            app.SNMRacquisitionparametersLabel.Text = 'SNMR acquisition parameters';
            app.KernelFileEditFieldLabel = uilabel(app.SNMRexperimentaldesignUIFigure);
            app.KernelFileEditFieldLabel.Text = 'Kernel File:';
            app.KernelFileEditField = uieditfield(app.SNMRexperimentaldesignUIFigure, 'text');
            app.KernelFileEditField.ValueChangedFcn = createCallbackFcn(app, @KernelFileEditFieldValueChanged, true);
            app.KernelFileEditField.Value = 'file.mrsk';
            app.TdeadsEditFieldLabel = uilabel(app.SNMRexperimentaldesignUIFigure);
            app.TdeadsEditFieldLabel.Text = 'Tdead [s]';
            app.TdeadsEditField = uieditfield(app.SNMRexperimentaldesignUIFigure, 'numeric');
            app.TdeadsEditField.Limits = [0 100];
            app.TdeadsEditField.Value = 0.005;
            app.FrequencyHzEditFieldLabel = uilabel(app.SNMRexperimentaldesignUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.SNMRexperimentaldesignUIFigure, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.FrequencyHzEditField.Value = 1000;
            app.TmaxsEditFieldLabel = uilabel(app.SNMRexperimentaldesignUIFigure);
            app.TmaxsEditFieldLabel.Text = 'Tmax [s]';
            app.TmaxsEditField = uieditfield(app.SNMRexperimentaldesignUIFigure, 'numeric');
            app.TmaxsEditField.Limits = [0 100];
            app.TmaxsEditField.Value = 0.5;
            app.LoadKernel = uibutton(app.SNMRexperimentaldesignUIFigure, 'push');
            app.LoadKernel.ButtonPushedFcn = createCallbackFcn(app, @LoadKernelButtonPushed, true);
            app.LoadKernel.Text = '...';
            app.LoadparametersfromdatafileButton = uibutton(app.SNMRexperimentaldesignUIFigure, 'push');
            app.LoadparametersfromdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersfromdatafileButtonPushed, true);
            app.LoadparametersfromdatafileButton.Text = 'Load parameters from data file';
            app.ReturnparametersButton = uibutton(app.SNMRexperimentaldesignUIFigure, 'push');
            app.ReturnparametersButton.ButtonPushedFcn = createCallbackFcn(app, @closeMenu, true);
            app.ReturnparametersButton.Text = 'Return parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SNMR_Exp_Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SNMRexperimentaldesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SNMRexperimentaldesignUIFigure)
        end
    end
end