classdef SurfaceWaves_Exp_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SWexperimentaldesignUIFigure  matlab.ui.Figure
        DispersionCurveexperimentaldesignLabel  matlab.ui.control.Label
        FminHzEditFieldLabel          matlab.ui.control.Label
        FminHzEditField               matlab.ui.control.NumericEditField
        MeasurementsEditFieldLabel    matlab.ui.control.Label
        MeasurementsEditField         matlab.ui.control.NumericEditField
        FmaxHzEditFieldLabel          matlab.ui.control.Label
        FmaxHzEditField               matlab.ui.control.NumericEditField
        LoadparametersfromdatafileButton  matlab.ui.control.Button
        ReturnparametersButton        matlab.ui.control.Button
        TypeSamplingList              matlab.ui.control.ListBox
    end

    properties (Access = public)
        Forward % Input and output of the 
        CloseGUI
    end

    properties (Access = private)
        DataFile % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CloseGUI = false;
            app.Forward.f_min = 0.05;% Default dead time
            app.FminHzEditField.Value = app.Forward.f_min;
            app.Forward.nbSteps = 1000;% Default sampling frequency
            app.MeasurementsEditField.Value = app.Forward.nbSteps;
            app.Forward.f_max  = 0.5;% Default maximum aquisition time
            app.FmaxHzEditField.Value = app.Forward.f_max ;
            Type = 'Linear';% Default sampling type
            app.DataFile = [];
            switch Type
                case 'Linear'
                    app.Forward.f_register = linspace(app.FminHzEditField.Value,app.Forward.f_max,app.Forward.nbSteps);
                case 'Logarithmic'
                    app.Forward.f_register = logspace(log10(app.FminHzEditField.Value),log10(app.Forward.f_max),app.Forward.nbSteps);
                case 'Custom'
                    uialert(app.SWexperimentaldesignUIFigure,'You will need to load a data file!','Warning','Icon','Warning');                    
            end
        end

        % Callback function: ReturnparametersButton, 
        % SWexperimentaldesignUIFigure
        function closeMenu(app, event)
            app.Forward.f_min = app.FminHzEditField.Value;
            app.Forward.nbSteps = app.MeasurementsEditField.Value;
            app.Forward.f_max = app.FmaxHzEditField.Value;
            Type = app.TypeSamplingList.Value;
            switch Type
                case 'Linear'
                    app.Forward.f_register = linspace(app.FminHzEditField.Value,app.Forward.f_max,app.Forward.nbSteps);
                case 'Logarithmic'
                    app.Forward.f_register = logspace(log10(app.FminHzEditField.Value),log10(app.Forward.f_max),app.Forward.nbSteps);
                case 'Custom'
                    if isempty(app.DataFile)
                        uialert(app.SWexperimentaldesignUIFigure,'You must first load a data file to load a custom scale!','Error');
                    end
            end
            selection = uiconfirm(app.SWexperimentaldesignUIFigure,'Return the parameters? ','Return','Options',{'Yes','Cancel'},...
                'DefaultOption',2,'CancelOption',2,'Icon','success');
            if strcmp(selection,'Yes')
                app.CloseGUI = true;
            end
        end

        % Button pushed function: LoadparametersfromdatafileButton
        function LoadparametersfromdatafileButtonPushed(app, event)
            [file ,path] = uigetfile({'*.maswd','MATLAB MASW data file (*.maswd)';'*','Geopsy file (no extension)'},'Select a data file');
            [~,~,ext] = fileparts([path file]);
            if strcmp(ext,'.maswd') % Matlab generated file
                dispersion = load([path file],'-mat');
                app.DataFile = dispersion.dispersion;
            elseif strcmp(ext,'') % Geopsy generated file
                fileID = fopen([path file],'r');
                c = textscan(fileID, '%f %f %f %f','Delimiter',' ','CommentStyle','#');
                fclose(fileID);
                dispersion.Curve.f = c{1};
                dispersion.Curve.ct = [c{2}].^(-1);
                dispersion.Curve.lambda = dispersion.Curve.ct./dispersion.Curve.f;
                app.DataFile = dispersion;
            else
                uialert(app.SWexperimentaldesignUIFigure,'The file you selected is not readable by the GUI! (Neither ''*.maswd'' or Geopsy multi-column file.)','Error');
            end
            app.FminHzEditField.Value = min(app.DataFile.Curve.f);
            app.MeasurementsEditField.Value = length(app.DataFile.Curve.f);
            app.FmaxHzEditField.Value = max(app.DataFile.Curve.f);
            app.Forward.f_register = app.DataFile.Curve.f;
            app.TypeSamplingList.Value = 'Custom';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SWexperimentaldesignUIFigure = uifigure;
            app.SWexperimentaldesignUIFigure.Name = 'SNMR experimental design';
            app.SWexperimentaldesignUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeMenu, true);
            app.DispersionCurveexperimentaldesignLabel = uilabel(app.SWexperimentaldesignUIFigure);
            app.DispersionCurveexperimentaldesignLabel.Text = 'Dispersion Curve experimental design';
            app.FminHzEditFieldLabel = uilabel(app.SWexperimentaldesignUIFigure);
            app.FminHzEditFieldLabel.Text = 'Fmin [Hz]';
            app.FminHzEditField = uieditfield(app.SWexperimentaldesignUIFigure, 'numeric');
            app.FminHzEditField.Limits = [0 100];
            app.FminHzEditField.Value = 10;
            app.MeasurementsEditFieldLabel = uilabel(app.SWexperimentaldesignUIFigure);
            app.MeasurementsEditFieldLabel.Text = '# Measurements';
            app.MeasurementsEditField = uieditfield(app.SWexperimentaldesignUIFigure, 'numeric');
            app.MeasurementsEditField.Limits = [10 Inf];
            app.MeasurementsEditField.Value = 100;
            app.FmaxHzEditFieldLabel = uilabel(app.SWexperimentaldesignUIFigure);
            app.FmaxHzEditFieldLabel.Text = 'Fmax [Hz]';
            app.FmaxHzEditField = uieditfield(app.SWexperimentaldesignUIFigure, 'numeric');
            app.FmaxHzEditField.Limits = [0 Inf];
            app.FmaxHzEditField.Value = 100;
            app.LoadparametersfromdatafileButton = uibutton(app.SWexperimentaldesignUIFigure, 'push');
            app.LoadparametersfromdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersfromdatafileButtonPushed, true);
            app.LoadparametersfromdatafileButton.Text = 'Load parameters from data file';
            app.ReturnparametersButton = uibutton(app.SWexperimentaldesignUIFigure, 'push');
            app.ReturnparametersButton.ButtonPushedFcn = createCallbackFcn(app, @closeMenu, true);
            app.ReturnparametersButton.Text = 'Return parameters';
            app.TypeSamplingList = uilistbox(app.SWexperimentaldesignUIFigure);
            app.TypeSamplingList.Items = {'Linear', 'Logarithmic', 'Custom'};
            app.TypeSamplingList.Value = 'Linear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SurfaceWaves_Exp_Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SWexperimentaldesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SWexperimentaldesignUIFigure)
        end
    end
end